classdef Floor3UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        RightDirection  matlab.ui.control.EditField
        LeftDirection   matlab.ui.control.EditField
        down2Lamp       matlab.ui.control.Lamp
        down1Lamp       matlab.ui.control.Lamp
        Elavator2Label  matlab.ui.control.Label
        Elavator1Label  matlab.ui.control.Label
        Elevator2Gauge  matlab.ui.control.LinearGauge
        Elevator1Gauge  matlab.ui.control.LinearGauge
        FLabel          matlab.ui.control.Label
        downButton      matlab.ui.control.Button
        door2Image      matlab.ui.control.Image
        door1Image      matlab.ui.control.Image
    end

    
    properties (Access = public)
        control
    end

    methods (Access = public)
        function closeDoor(app, id)
            switch id
                case 1
                    if app.control.elevator_1.Alarm == 0
                        app.door1Image.ImageSource = 'close_f.png';
                    end
                case 2
                    if app.control.elevator_2.Alarm == 0
                        app.door2Image.ImageSource = 'close_f.png';
                    end
            end
            if strcmp(app.downButton.Enable, 'off')
                app.downButton.Enable = 'on';
            end
        end

        function openDoor(app, id)
            switch id 
                case 1
                    if app.control.elevator_1.speed == 0
                        app.door1Image.ImageSource = 'open_f.png';
                    end
                case 2
                    if app.control.elevator_1.speed == 0
                        app.door2Image.ImageSource = 'open_f.png';
                    end
            end
        end

        function switchLamp(app, id, state)
            red = [1.00,0.00,0.00];
            green = [0.39,0.83,0.07];
            if strcmp(state, 'on')
                color = green;
            elseif strcmp(state, 'off')
                color = red;
            end
            switch id
                case 1
                    app.down1Lamp.Color = color;
                case 2
                    app.down2Lamp.Color = color;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: downButton
        function downButtonPushed(app, event)
            app.downButton.Enable = 'off';
            if(app.control.goto(0, 2) == 1)
                app.downButton.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '3楼外部UI';
            app.door1Image = uiimage(app.UIFigure);
            app.door1Image.ImageSource = 'close_f.png';
            app.door2Image = uiimage(app.UIFigure);
            app.door2Image.ImageSource = 'close_f.png';
            app.downButton = uibutton(app.UIFigure, 'push');
            app.downButton.ButtonPushedFcn = createCallbackFcn(app, @downButtonPushed, true);
            app.downButton.Text = '↓';
            app.FLabel = uilabel(app.UIFigure);
            app.FLabel.Text = '        3F';
            app.Elevator1Gauge = uigauge(app.UIFigure, 'linear');
            app.Elevator1Gauge.Limits = [1 3];
            app.Elevator1Gauge.MajorTicks = [1 2 3];
            app.Elevator1Gauge.MinorTicks = [1 2 3];
            app.Elevator1Gauge.Value = 1;
            app.Elevator2Gauge = uigauge(app.UIFigure, 'linear');
            app.Elevator2Gauge.Limits = [1 3];
            app.Elevator2Gauge.MajorTicks = [1 2 3];
            app.Elevator2Gauge.MinorTicks = [1 2 3];
            app.Elevator2Gauge.Value = 1;
            app.Elavator1Label = uilabel(app.UIFigure);
            app.Elavator1Label.Text = 'Elavator 1';
            app.Elavator2Label = uilabel(app.UIFigure);
            app.Elavator2Label.Text = 'Elavator 2';
            app.down1Lamp = uilamp(app.UIFigure);
            app.down2Lamp = uilamp(app.UIFigure);
            app.LeftDirection = uieditfield(app.UIFigure, 'text');
            app.RightDirection = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Floor3UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end