classdef ElevatorUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Direction               matlab.ui.control.EditField
        ControlpanelPanel       matlab.ui.container.Panel
        OverWeightSensorButton  matlab.ui.control.Button
        HumanEnterSensorButton  matlab.ui.control.Button
        CallButton              matlab.ui.control.Button
        AlarmButton             matlab.ui.control.Button
        closeButton             matlab.ui.control.Button
        openButton              matlab.ui.control.Button
        Button_1                matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        Button_3                matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        ElevatorGauge           matlab.ui.control.LinearGauge
        doorImage               matlab.ui.control.Image
    end

    
    properties (Access = public)
        Control
        Elevator
        ID
    end
    
    methods (Access = public)
        
        function openDoor(app)
            if (app.Elevator.speed == 0)
                app.doorImage.ImageSource = 'open_e.png';
            end
        end
           
        function closeDoor(app)
            if app.Elevator.Alarm == 0
                app.doorImage.ImageSource = 'close_e.png';
                app.Elevator.state = 0;
            end
        end
        
        function update(app)
            if app.Elevator.Alarm == 0
                app.ElevatorGauge.Value = 1 + app.Elevator.height/3;
                state = app.Elevator.state;
                switch state
                    case 0
                        app.closeDoor()
                    case 1
                        app.openDoor()
                end
            end
        end

        function enableButton(app, floor)
            switch floor
                case 0
                    app.Button_1.Enable='on';
                case 1
                    app.Button_2.Enable='on';
                case 2
                    app.Button_3.Enable='on';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Controller, elevator, elevatorID)
            app.Control = Controller;
            app.Elevator = elevator;
            app.ID = elevatorID;

            if (app.ID == 1)
                app.Label.Text = "Elavator 1";
                app.UIFigure.Position = [0,410,534,413];
            else
                app.Label.Text = "Elavator 2";
                app.UIFigure.Position = [0,0,534,413];
            end
        end

        % Button pushed function: openButton
        function openButtonPushed(app, event)
            app.openButton.Enable = "off";
            app.Control.pushOpenDoorAtFloor(app.ID, app.Elevator.position)
            app.openButton.Enable = "on";
        end

        % Button pushed function: closeButton
        function closeButtonPushed(app, event)
            app.closeButton.Enable = "off";
            app.Control.closeDoorAtFloor(app.ID, app.Elevator.position)
            app.closeButton.Enable = "on";
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.Button_1.Enable = 'off';
            
            %temp = 0;
            %while temp == 0
                %if strcmp(app.Elevator.direction, 'idle')
                    %temp = 1;
                %end
            %end

            %if temp == 1
            while strcmp(app.Elevator.direction, 'idle') ~= 1
                pause(0.1)
            end
            if(app.Control.goto(app.ID, 0) == 1)
                app.Button_1.Enable = 'on';
            end
            %end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Button_2.Enable='off';

            %temp = 0;
            %while temp == 0
                %if strcmp(app.Elevator.direction, 'idle')
                    %temp = 1;
                %end
            %end

            %if temp == 1
            while strcmp(app.Elevator.direction, 'idle') ~= 1
                pause(0.1)
            end
            if(app.Control.goto(app.ID, 1) == 1)
                app.Button_2.Enable = 'on';
            end
            %end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Button_3.Enable = 'off';
            while strcmp(app.Elevator.direction, 'idle') ~= 1
                pause(0.1)
            end
            %temp = 0;
            %while temp == 0
                %if strcmp(app.Elevator.direction, 'idle')
                    %temp = 1;
                %end
            %end

            %if temp == 1
            if (app.Control.goto(app.ID, 2) == 1)
                app.Button_3.Enable = 'on';
            end
            %end
        end

        % Button pushed function: AlarmButton
        function AlarmButtonPushed(app, event)
            while strcmp(app.Elevator.direction, 'idle') ~= 1
                pause(0.1)
            end
            if (app.Elevator.Alarm == 0)
                app.AlarmButton.Text = "unAlarm";
                [x,Fs] = audioread('Alarm.wav');
                sound(x, Fs);
                app.Elevator.Alarm = 1;
                pos = app.Elevator.position;
                id = app.ID;
                app.openDoor()
                app.Button_1.Enable = 'off';
                app.Button_2.Enable = 'off';
                app.Button_3.Enable = 'off';
                app.openButton.Enable = 'off';
                app.closeButton.Enable = 'off';
                switch pos
                    case 0
                        app.Control.elevatorOutUI_1.openDoor(id);
                        app.Control.elevatorOutUI_1.switchLamp(id, 'off');
                    case 1
                        app.Control.elevatorOutUI_2.openDoor(id);
                        app.Control.elevatorOutUI_2.switchLamp(id, 'off');
                    case 2
                        app.Control.elevatorOutUI_3.openDoor(id);
                        app.Control.elevatorOutUI_3.switchLamp(id, 'off');
                end
            elseif(app.Elevator.Alarm == 1)
                app.AlarmButton.Text = "Alarm";
                clear sound;
                app.Elevator.Alarm = 0;
                pos = app.Elevator.position;
                id = app.ID;
                app.closeDoor()
                app.Button_1.Enable = 'on';
                app.Button_2.Enable = 'on';
                app.Button_3.Enable = 'on';
                app.openButton.Enable = 'on';
                app.closeButton.Enable = 'on';
                switch pos
                    case 0
                        app.Control.elevatorOutUI_1.closeDoor(id);
                    case 1
                        app.Control.elevatorOutUI_2.closeDoor(id);
                    case 2
                        app.Control.elevatorOutUI_3.closeDoor(id);
                end
            end
        end

        % Button pushed function: CallButton
        function CallButtonPushed(app, event)
            Called;
        end

        % Button pushed function: HumanEnterSensorButton
        function HumanEnterSensorButtonPushed(app, event)
            app.HumanEnterSensorButton.Enable = "off";
            app.Control.pushOpenDoorAtFloor(app.ID, app.Elevator.position)
            app.HumanEnterSensorButton.Enable = "on";
        end

        % Button pushed function: OverWeightSensorButton
        function OverWeightSensorButtonPushed(app, event)

            if (app.Elevator.Alarm == 0)
                [x,Fs] = audioread('Alarm.wav');
                sound(x, Fs);
                app.Elevator.Alarm = 1;
                pos = app.Elevator.position;
                id = app.ID;
                app.openDoor()
                switch pos
                    case 0
                        app.Control.elevatorOutUI_1.openDoor(id);
                        app.Control.elevatorOutUI_1.switchLamp(id, 'off');
                    case 1
                        app.Control.elevatorOutUI_2.openDoor(id);
                        app.Control.elevatorOutUI_2.switchLamp(id, 'off');
                    case 2
                        app.Control.elevatorOutUI_3.openDoor(id);
                        app.Control.elevatorOutUI_3.switchLamp(id, 'off');
                end
            elseif(app.Elevator.Alarm == 1)
                clear sound;
                app.Elevator.Alarm = 0;
                pos = app.Elevator.position;
                id = app.ID;
                app.closeDoor()
                switch pos
                    case 0
                        app.Control.elevatorOutUI_1.closeDoor(id);
                    case 1
                        app.Control.elevatorOutUI_2.closeDoor(id);
                    case 2
                        app.Control.elevatorOutUI_3.closeDoor(id);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '电梯内UI';
            app.doorImage = uiimage(app.UIFigure);
            app.doorImage.ImageSource = 'close_e.png';
            app.ElevatorGauge = uigauge(app.UIFigure, 'linear');
            app.ElevatorGauge.Limits = [1 3];
            app.ElevatorGauge.MajorTicks = [1 2 3];
            app.ElevatorGauge.MinorTicks = [1 2 3];
            app.ElevatorGauge.Value = 1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Elavator 1';
            app.ControlpanelPanel = uipanel(app.UIFigure);
            app.ControlpanelPanel.Title = 'Control panel';
            app.Button_3 = uibutton(app.ControlpanelPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_2 = uibutton(app.ControlpanelPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_1 = uibutton(app.ControlpanelPanel, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.openButton = uibutton(app.ControlpanelPanel, 'push');
            app.openButton.ButtonPushedFcn = createCallbackFcn(app, @openButtonPushed, true);
            app.openButton.Text = 'open';
            app.closeButton = uibutton(app.ControlpanelPanel, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.closeButton.Text = 'close';
            app.AlarmButton = uibutton(app.ControlpanelPanel, 'push');
            app.AlarmButton.ButtonPushedFcn = createCallbackFcn(app, @AlarmButtonPushed, true);
            app.AlarmButton.Text = 'Alarm';
            app.CallButton = uibutton(app.ControlpanelPanel, 'push');
            app.CallButton.ButtonPushedFcn = createCallbackFcn(app, @CallButtonPushed, true);
            app.CallButton.Text = 'Call';
            app.HumanEnterSensorButton = uibutton(app.ControlpanelPanel, 'push');
            app.HumanEnterSensorButton.ButtonPushedFcn = createCallbackFcn(app, @HumanEnterSensorButtonPushed, true);
            app.HumanEnterSensorButton.Enable = 'off';
            app.HumanEnterSensorButton.Text = 'Human Enter Sensor';
            app.OverWeightSensorButton = uibutton(app.ControlpanelPanel, 'push');
            app.OverWeightSensorButton.ButtonPushedFcn = createCallbackFcn(app, @OverWeightSensorButtonPushed, true);
            app.OverWeightSensorButton.Enable = 'off';
            app.OverWeightSensorButton.Text = 'Over Weight Sensor';
            app.Direction = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElevatorUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end