classdef customize_game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Panel        matlab.ui.container.Panel
        GamePanel    matlab.ui.container.Panel
        GridLayout   matlab.ui.container.GridLayout
        b33          matlab.ui.control.Button
        b32          matlab.ui.control.Button
        b54          matlab.ui.control.Button
        b53          matlab.ui.control.Button
        b52          matlab.ui.control.Button
        b51          matlab.ui.control.Button
        b44          matlab.ui.control.Button
        b43          matlab.ui.control.Button
        b42          matlab.ui.control.Button
        b41          matlab.ui.control.Button
        b34          matlab.ui.control.Button
        b31          matlab.ui.control.Button
        b24          matlab.ui.control.Button
        b23          matlab.ui.control.Button
        b22          matlab.ui.control.Button
        b21          matlab.ui.control.Button
        b14          matlab.ui.control.Button
        b13          matlab.ui.control.Button
        b12          matlab.ui.control.Button
        b11          matlab.ui.control.Button
        cc           matlab.ui.control.Button
        s4           matlab.ui.control.Button
        s3           matlab.ui.control.Button
        s2           matlab.ui.control.Button
        s1           matlab.ui.control.Button
        gy           matlab.ui.control.Button
        mc           matlab.ui.control.Button
        zf           matlab.ui.control.Button
        zy           matlab.ui.control.Button
        hz           matlab.ui.control.Button
        c_cc         matlab.ui.control.Button
        c_general_h  matlab.ui.control.Button
        c_general_v  matlab.ui.control.Button
        c_s          matlab.ui.control.Button
        begin        matlab.ui.control.Button
        clear        matlab.ui.control.Button
        home         matlab.ui.control.Button
        Label_4      matlab.ui.control.Label
    end


    properties (Access = public)
        piece_type = 0;
        cc_num = 0;
        general_num = 0;
        gy_num = 0;
        soldier_num = 0;

        game_board; % 后端的棋盘类
        generals = {'mc', 'zf', 'zy', 'hz'};
    end
    
    methods (Access = public)
        
        % @contructor
        % @param {counter} controller: Controller for the counter


        function place_success = place_piece(app, row, col)
            place_success = true;
            
            cc_t = struct('size', [2 2], ...
                    'min_row', 4, ...
                    'min_col', 3);
            g_v_t = struct('size', [2 1], ... 
                    'min_row', 4, ...
                    'min_col', 4);
            g_h_t = struct('size', [1 2], ... 
                    'min_row', 5, ...
                    'min_col', 3);
            s_t = struct('size', [1 1], ...
                    'min_row', 5, ...
                    'min_col', 4);
            
            switch app.piece_type
                case 0 % none % branch tc1.3.1.1
                    return
                case 1 % cc % branch tc1.3.1.2
                    if (row > cc_t.min_row) || (col > cc_t.min_col) % branch tc1.3.1.3
                        place_success = false;
                        return
                    end
                    for r = 0:1:cc_t.size(1)-1
                        for l = 0:1:cc_t.size(2)-1
                            if (app.game_board.occupy_matrix(row+r, col+l) == 1)
                                place_success = false;
                                return
                            end
                        end
                    end

                    for r = 0:1:cc_t.size(1)-1
                        for l = 0:1:cc_t.size(2)-1
                            button_name = sprintf('b%d%d', row+r, col+l);
%                             disp(button_name);
                            app.(button_name).Visible = 'off';
                            app.game_board.occupy_matrix(row+r,col+l) = 1;
                        end
                    end

                    app.game_board.cc_row = [row,row+1];
                    app.game_board.cc_col = [col,col+1];
                    app.cc.Layout.Row = [row,row+1];
                    app.cc.Layout.Column = [col,col+1];
                    app.cc.Visible = 1;
                    app.cc_num = 1;
                    app.piece_type = 0;
                    app.c_cc.Enable = 'off';

%                     if app.cc_num + app.general_num + app.gy_num + app.soldier_num == 10
%                         for r = 1 : 1 : 5
%                             for l = 1 : 1: 4
%                                 app.(sprintf('b%d%d', r, l)).Visible = 'off';
%                             end
%                         end
%                         app.begin.Enable = 'on';
%                     end

                case 2 %% general_vertical % branch tc1.3.1.4
                    if (row > g_v_t.min_row) || (col > g_v_t.min_col) % branch tc1.3.1.5
                        place_success = false;
                        return
                    end
                    for r = 0:1:g_v_t.size(1)-1
                        for l = 0:1:g_v_t.size(2)-1
                            if (app.game_board.occupy_matrix(row+r, col+l) == 1)
                                place_success = false;
                                return
                            end
                        end
                    end

                    for r = 0:1:g_v_t.size(1)-1
                        for l = 0:1:g_v_t.size(2)-1
                            button_name = sprintf('b%d%d', row+r, col+l);
                            disp(button_name);
                            app.(button_name).Visible = 'off';
                            app.game_board.occupy_matrix(row+r,col+l) = 1;
                        end
                    end

                    general_name = app.generals(app.general_num+1);
                    xx_row = sprintf('%s_row', general_name{1});
                    xx_col = sprintf('%s_col', general_name{1});
                    app.game_board.(xx_row) = [row,row+1];
                    app.game_board.(xx_col) = col;
                    app.(general_name{1}).Layout.Row = [row,row+1];
                    app.(general_name{1}).Layout.Column = col;
                    app.(general_name{1}).Visible = 1;
                    app.general_num = app.general_num + 1;

                    if app.general_num == 4 % branch tc1.3.1.6
                        app.piece_type = 0;
%                         app.c_general_h.Enable = 'off';
                        app.c_general_v.Enable = 'off';
                    end

%                     if app.cc_num + app.general_num + app.gy_num + app.soldier_num == 10
%                         for r = 1 : 1 : 5
%                             for l = 1 : 1: 4
%                                 app.(sprintf('b%d%d', r, l)).Visible = 'off';
%                             end
%                         end
%                         app.begin.Enable = 'on';
%                     end

                case 3 %% general_horizontal % branch tc1.3.1.7
                    if (row > g_h_t.min_row) || (col > g_h_t.min_col) % branch tc1.3.1.8
                        place_success = false;
                        return
                    end
                    for r = 0:1:g_h_t.size(1)-1
                        for l = 0:1:g_h_t.size(2)-1
                            if (app.game_board.occupy_matrix(row+r, col+l) == 1)
                                place_success = false;
                                return
                            end
                        end
                    end

                    for r = 0:1:g_h_t.size(1)-1
                        for l = 0:1:g_h_t.size(2)-1
                            button_name = sprintf('b%d%d', row+r, col+l);
                            disp(button_name);
                            app.(button_name).Visible = 'off';
                            app.game_board.occupy_matrix(row+r,col+l) = 1;
                        end
                    end

%                     general_name = app.generals(app.general_num+1);
                    general_name = 'gy';
                    xx_row = sprintf('%s_row', general_name);
                    xx_col = sprintf('%s_col', general_name);
                    app.game_board.(xx_row) = row;
                    app.game_board.(xx_col) = [col, col+1];
                    app.(general_name).Layout.Row = row;
                    app.(general_name).Layout.Column = [col, col+1];
                    app.(general_name).Visible = 1;
                    app.gy_num = app.gy_num + 1;

                    if app.gy_num >= 1 % branch tc1.3.1.9
                        app.piece_type = 0;
                        app.c_general_h.Enable = 'off';
                    end

%                     if app.cc_num + app.general_num + app.gy_num + app.soldier_num == 10
%                         for r = 1 : 1 : 5
%                             for l = 1 : 1: 4
%                                 app.(sprintf('b%d%d', r, l)).Visible = 'off';
%                             end
%                         end
%                         app.begin.Enable = 'on';
%                     end
                
                case 4 %% solider % branch tc1.3.1.10
%                     if (row > s_t.min_row) || (col > s_t.min_col) % branch tc1.3.1.11
%                         place_success = false;
%                         return
%                     end
                    for r = 0:1:s_t.size(1)-1
                        for l = 0:1:s_t.size(2)-1
                            if (app.game_board.occupy_matrix(row+r, col+l) == 1)
                                place_success = false;
                                return
                            end
                        end
                    end

                    for r = 0:1:s_t.size(1)-1
                        for l = 0:1:s_t.size(2)-1
                            button_name = sprintf('b%d%d', row+r, col+l);
                            disp(button_name);
                            app.(button_name).Visible = 'off';
                            app.game_board.occupy_matrix(row+r,col+l) = 1;
                        end
                    end

                    app.game_board.(sprintf('s%d_row', app.soldier_num+1)) = row;
                    app.game_board.(sprintf('s%d_col', app.soldier_num+1)) = col;
                    app.(sprintf('s%d', app.soldier_num+1)).Layout.Row = row;
                    app.(sprintf('s%d', app.soldier_num+1)).Layout.Column = col;
                    app.(sprintf('s%d', app.soldier_num+1)).Visible = 1;
                    app.soldier_num = app.soldier_num + 1;
 
                    if app.soldier_num == 4 % branch tc1.3.1.11
                        app.piece_type = 0;
                        app.c_s.Enable = 'off';
                    end

%                     if app.cc_num + app.general_num + app.gy_num + app.soldier_num == 10
%                         for r = 1 : 1 : 5
%                             for l = 1 : 1: 4
%                                 app.(sprintf('b%d%d', r, l)).Visible = 'off';
%                             end
%                         end
%                         app.begin.Enable = 'on';
%                     end
            end


            if app.cc_num + app.general_num + app.gy_num + app.soldier_num == 10 % branch tc1.3.1.12
                for r = 1 : 1 : 5
                    for l = 1 : 1: 4
                        app.(sprintf('b%d%d', r, l)).Visible = 'off';
                    end
                end
                app.begin.Enable = 'on';
            end

        end

        
        function reset_all(app)
            % Statement Tcover 1.3.2
            % 站位按钮恢复
            for r = 1 : 1 : 5
                for l = 1 : 1 : 4
                    app.(sprintf('b%d%d', r, l)).Visible = 'on';
                end
            end
            app.begin.Enable = 'off';
            % num 
            app.cc_num = 0;
            app.general_num = 0;
            app.soldier_num = 0;
            app.piece_type = 0;
            app.gy_num = 0;
            % game_board
            app.game_board.clear_board();
            % characters
            app.cc.Visible = 'off';
            app.s1.Visible = 'off';
            app.s2.Visible = 'off';
            app.s3.Visible = 'off';
            app.s4.Visible = 'off';
            app.mc.Visible = 'off';
            app.zy.Visible = 'off';
            app.gy.Visible = 'off';
            app.zf.Visible = 'off';
            app.hz.Visible = 'off';
            % type
            app.c_cc.Enable = 'on';
            app.c_s.Enable = 'on';
            app.c_general_h.Enable = 'on';
            app.c_general_v.Enable = 'on';
        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, game_board)
            app.game_board = game_board;
            app.game_board.clear_board();
        end

        % Button pushed function: c_cc
        function c_ccButtonPushed(app, event)
            app.piece_type = 1;
            disp("selected type: " + num2str(app.piece_type));
        end

        % Button pushed function: c_general_v
        function c_general_vButtonPushed(app, event)
            app.piece_type = 2;
            disp("selected type: " + num2str(app.piece_type));
        end

        % Button pushed function: c_general_h
        function c_general_hButtonPushed(app, event)
            app.piece_type = 3;
            disp("selected type: " + num2str(app.piece_type));
        end

        % Button pushed function: c_s
        function c_sButtonPushed(app, event)
            app.piece_type = 4;
            disp("selected type: " + num2str(app.piece_type));
        end

        % Button pushed function: b11
        function b11ButtonPushed(app, event)
            place_piece(app, 1, 1);
        end

        % Button pushed function: b12
        function b12ButtonPushed(app, event)
            place_piece(app, 1, 2);
        end

        % Button pushed function: b13
        function b13ButtonPushed(app, event)
            place_piece(app, 1, 3);
        end

        % Button pushed function: b14
        function b14ButtonPushed(app, event)
            place_piece(app, 1, 4);
        end

        % Button pushed function: b21
        function b21ButtonPushed(app, event)
            place_piece(app, 2, 1);
        end

        % Button pushed function: b22
        function b22ButtonPushed(app, event)
            place_piece(app, 2, 2);
        end

        % Button pushed function: b23
        function b23ButtonPushed(app, event)
            place_piece(app, 2, 3);
        end

        % Button pushed function: b24
        function b24ButtonPushed(app, event)
            place_piece(app, 2, 4);
        end

        % Button pushed function: b31
        function b31ButtonPushed(app, event)
            place_piece(app, 3, 1);
        end

        % Button pushed function: b34
        function b34ButtonPushed(app, event)
            place_piece(app, 3, 4);
        end

        % Button pushed function: b41
        function b41ButtonPushed(app, event)
            place_piece(app, 4, 1);
        end

        % Button pushed function: b42
        function b42ButtonPushed(app, event)
            place_piece(app, 4, 2);
        end

        % Button pushed function: b43
        function b43ButtonPushed(app, event)
            place_piece(app, 4, 3);
        end

        % Button pushed function: b44
        function b44ButtonPushed(app, event)
            place_piece(app, 4, 4);
        end

        % Button pushed function: b51
        function b51ButtonPushed(app, event)
            place_piece(app, 5, 1);
        end

        % Button pushed function: b52
        function b52ButtonPushed(app, event)
            place_piece(app, 5, 2);
        end

        % Button pushed function: b53
        function b53ButtonPushed(app, event)
            place_piece(app, 5, 3);
        end

        % Button pushed function: b54
        function b54ButtonPushed(app, event)
            place_piece(app, 5, 4);
        end

        % Button pushed function: b32
        function b32ButtonPushed(app, event)
            place_piece(app, 3, 2);
        end

        % Button pushed function: b33
        function b33ButtonPushed(app, event)
            place_piece(app, 3, 3);
        end

        % Button pushed function: clear
        function clearButtonPushed(app, event)
            reset_all(app);
        end

        % Button pushed function: begin
        function beginButtonPushed(app, event)
            app.game_board.initial_matrix = app.game_board.occupy_matrix;
            gameui = game(-1, app.game_board);
            app.game_board.game = gameui;
            delete(app);
        end

        % Button pushed function: home
        function homeButtonPushed(app, event)
            homeui = game_home(app.game_board);
            app.game_board.home = homeui;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '华容道';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '自定义布局';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '自定义游戏';
            app.home = uibutton(app.Panel, 'push');
            app.home.ButtonPushedFcn = createCallbackFcn(app, @homeButtonPushed, true);
            app.home.Text = '返回主页';
            app.clear = uibutton(app.Panel, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clear.Text = '清空';
            app.begin = uibutton(app.Panel, 'push');
            app.begin.ButtonPushedFcn = createCallbackFcn(app, @beginButtonPushed, true);
            app.begin.Enable = 'off';
            app.begin.Text = '开始';
            app.c_s = uibutton(app.Panel, 'push');
            app.c_s.ButtonPushedFcn = createCallbackFcn(app, @c_sButtonPushed, true);
            app.c_s.Text = '卒';
            app.c_general_v = uibutton(app.Panel, 'push');
            app.c_general_v.ButtonPushedFcn = createCallbackFcn(app, @c_general_vButtonPushed, true);
            app.c_general_v.Text = '将';
            app.c_general_h = uibutton(app.Panel, 'push');
            app.c_general_h.ButtonPushedFcn = createCallbackFcn(app, @c_general_hButtonPushed, true);
            app.c_general_h.Text = '将';
            app.c_cc = uibutton(app.Panel, 'push');
            app.c_cc.ButtonPushedFcn = createCallbackFcn(app, @c_ccButtonPushed, true);
            app.c_cc.Text = '曹操';
            app.GamePanel = uipanel(app.Panel);
            app.GamePanel.Title = 'Game Panel';
            app.GridLayout = uigridlayout(app.GamePanel);
            app.hz = uibutton(app.GridLayout, 'push');
            app.hz.Visible = 'off';
            app.hz.Text = '黄忠';
            app.zy = uibutton(app.GridLayout, 'push');
            app.zy.Visible = 'off';
            app.zy.Text = '赵云';
            app.zf = uibutton(app.GridLayout, 'push');
            app.zf.Visible = 'off';
            app.zf.Text = '张飞';
            app.mc = uibutton(app.GridLayout, 'push');
            app.mc.Visible = 'off';
            app.mc.Text = '马超';
            app.gy = uibutton(app.GridLayout, 'push');
            app.gy.Visible = 'off';
            app.gy.Text = '关羽';
            app.s1 = uibutton(app.GridLayout, 'push');
            app.s1.Visible = 'off';
            app.s1.Text = '卒';
            app.s2 = uibutton(app.GridLayout, 'push');
            app.s2.Visible = 'off';
            app.s2.Text = '卒';
            app.s3 = uibutton(app.GridLayout, 'push');
            app.s3.Visible = 'off';
            app.s3.Text = '卒';
            app.s4 = uibutton(app.GridLayout, 'push');
            app.s4.Visible = 'off';
            app.s4.Text = '卒';
            app.cc = uibutton(app.GridLayout, 'push');
            app.cc.Visible = 'off';
            app.cc.Text = ' 曹操';
            app.b11 = uibutton(app.GridLayout, 'push');
            app.b11.ButtonPushedFcn = createCallbackFcn(app, @b11ButtonPushed, true);
            app.b11.Text = '';
            app.b12 = uibutton(app.GridLayout, 'push');
            app.b12.ButtonPushedFcn = createCallbackFcn(app, @b12ButtonPushed, true);
            app.b12.Text = '';
            app.b13 = uibutton(app.GridLayout, 'push');
            app.b13.ButtonPushedFcn = createCallbackFcn(app, @b13ButtonPushed, true);
            app.b13.Text = '';
            app.b14 = uibutton(app.GridLayout, 'push');
            app.b14.ButtonPushedFcn = createCallbackFcn(app, @b14ButtonPushed, true);
            app.b14.Text = '';
            app.b21 = uibutton(app.GridLayout, 'push');
            app.b21.ButtonPushedFcn = createCallbackFcn(app, @b21ButtonPushed, true);
            app.b21.Text = '';
            app.b22 = uibutton(app.GridLayout, 'push');
            app.b22.ButtonPushedFcn = createCallbackFcn(app, @b22ButtonPushed, true);
            app.b22.Text = '';
            app.b23 = uibutton(app.GridLayout, 'push');
            app.b23.ButtonPushedFcn = createCallbackFcn(app, @b23ButtonPushed, true);
            app.b23.Text = '';
            app.b24 = uibutton(app.GridLayout, 'push');
            app.b24.ButtonPushedFcn = createCallbackFcn(app, @b24ButtonPushed, true);
            app.b24.Text = '';
            app.b31 = uibutton(app.GridLayout, 'push');
            app.b31.ButtonPushedFcn = createCallbackFcn(app, @b31ButtonPushed, true);
            app.b31.Text = '';
            app.b34 = uibutton(app.GridLayout, 'push');
            app.b34.ButtonPushedFcn = createCallbackFcn(app, @b34ButtonPushed, true);
            app.b34.Text = '';
            app.b41 = uibutton(app.GridLayout, 'push');
            app.b41.ButtonPushedFcn = createCallbackFcn(app, @b41ButtonPushed, true);
            app.b41.Text = '';
            app.b42 = uibutton(app.GridLayout, 'push');
            app.b42.ButtonPushedFcn = createCallbackFcn(app, @b42ButtonPushed, true);
            app.b42.Text = '';
            app.b43 = uibutton(app.GridLayout, 'push');
            app.b43.ButtonPushedFcn = createCallbackFcn(app, @b43ButtonPushed, true);
            app.b43.Text = '';
            app.b44 = uibutton(app.GridLayout, 'push');
            app.b44.ButtonPushedFcn = createCallbackFcn(app, @b44ButtonPushed, true);
            app.b44.Text = '';
            app.b51 = uibutton(app.GridLayout, 'push');
            app.b51.ButtonPushedFcn = createCallbackFcn(app, @b51ButtonPushed, true);
            app.b51.Text = '';
            app.b52 = uibutton(app.GridLayout, 'push');
            app.b52.ButtonPushedFcn = createCallbackFcn(app, @b52ButtonPushed, true);
            app.b52.Text = '';
            app.b53 = uibutton(app.GridLayout, 'push');
            app.b53.ButtonPushedFcn = createCallbackFcn(app, @b53ButtonPushed, true);
            app.b53.Text = '';
            app.b54 = uibutton(app.GridLayout, 'push');
            app.b54.ButtonPushedFcn = createCallbackFcn(app, @b54ButtonPushed, true);
            app.b54.Text = '';
            app.b32 = uibutton(app.GridLayout, 'push');
            app.b32.ButtonPushedFcn = createCallbackFcn(app, @b32ButtonPushed, true);
            app.b32.Text = '';
            app.b33 = uibutton(app.GridLayout, 'push');
            app.b33.ButtonPushedFcn = createCallbackFcn(app, @b33ButtonPushed, true);
            app.b33.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = customize_game(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end