classdef game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlayUIFigure  matlab.ui.Figure
        GamingPanel   matlab.ui.container.Panel
        help          matlab.ui.control.Button
        level_name    matlab.ui.control.Label
        step_count    matlab.ui.control.Label
        down          matlab.ui.control.Button
        right         matlab.ui.control.Button
        left          matlab.ui.control.Button
        up            matlab.ui.control.Button
        next_level    matlab.ui.control.Button
        last_level    matlab.ui.control.Button
        undo          matlab.ui.control.Button
        reset         matlab.ui.control.Button
        home          matlab.ui.control.Button
        GamePanel     matlab.ui.container.Panel
        GridLayout    matlab.ui.container.GridLayout
        cc            matlab.ui.control.Button
        s4            matlab.ui.control.Button
        s3            matlab.ui.control.Button
        s2            matlab.ui.control.Button
        s1            matlab.ui.control.Button
        gy            matlab.ui.control.Button
        mc            matlab.ui.control.Button
        zf            matlab.ui.control.Button
        zy            matlab.ui.control.Button
        hz            matlab.ui.control.Button
        Label_4       matlab.ui.control.Label
    end


    properties (Access = public)
        game_board;
        game_mode;
        seleted_piece = 0; % 初始化为 0
        
        level_names = {'连战连捷' '身先士卒' '一夫当关' '烽火不熄' '有勇有谋'
            '整齐排行' '屯兵东路' '大呼驰下' '四面楚歌' '一横布局'};
        
        step_num = 0;

        iswin = 0;

        history_list = struct('selected_piece', {}, 'dir', {}); % 每一步的记录

        layout_list = []; % 每一步之后，棋面的记录
        
        layout_0 = struct('occupy_matrix', [1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            1, 0, 0, 1], ...
            'cc_row', [3 4], ... %A
            'cc_col', [2 3], ...
            'mc_row', [1 2], ... %1
            'mc_col', 1, ...
            'zf_row', [1 2], ... %2
            'zf_col', 4, ...
            'zy_row', [3 4], ... %3
            'zy_col', 1, ...
            'gy_row', 2, ...     %4
            'gy_col', [2 3], ...
            'hz_row', [3 4], ... %5
            'hz_col', 4, ...
            's1_row', 1, ... %6
            's1_col', 2, ...
            's2_row', 1, ... %7
            's2_col', 3, ...
            's3_row', 5, ... %8
            's3_col', 1, ...
            's4_row', 5, ... %9
            's4_col', 4);
%         连战连捷
        layout_1 = struct('occupy_matrix', [ ...
            1, 1, 1, 0; ...
            1, 1, 1, 0; ...
            1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 1, 1], ...
            'cc_row', [2 3], ... %A
            'cc_col', [1 2], ...
            'mc_row', [4 5], ... %1
            'mc_col', 2, ...
            'zf_row', [1 2], ... %2
            'zf_col', 3, ...
            'zy_row', [3 4], ... %3
            'zy_col', 3, ...
            'gy_row', 1, ...     %4
            'gy_col', [1 2], ...
            'hz_row', [4 5], ... %5
            'hz_col', 4, ...
            's1_row', 3, ... %6
            's1_col', 4, ...
            's2_row', 4, ... %7
            's2_col', 1, ...
            's3_row', 5, ... %8
            's3_col', 1, ...
            's4_row', 5, ... %9
            's4_col', 3);
% 身先士卒
        layout_2 = struct('occupy_matrix', [ ...
            1, 1, 1, 1; ...
            0, 1, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 1, 0], ...
            'cc_row', [1 2], ... %A
            'cc_col', [3 4], ...
            'mc_row', [4 5], ... %1
            'mc_col', 1, ...
            'zf_row', [2 3], ... %2
            'zf_col', 2, ...
            'zy_row', [3 4], ... %3
            'zy_col', 4, ...
            'gy_row', 1, ...     %4
            'gy_col', [1 2], ...
            'hz_row', [4 5], ... %6
            'hz_col', 3, ...
            's1_row', 3, ... %5
            's1_col', 1, ...
            's2_row', 3, ... %7
            's2_col', 3, ...
            's3_row', 4, ... %8
            's3_col', 2, ...
            's4_row', 5, ... %9
            's4_col', 2);
        
%         一夫当关
        layout_3 = struct('occupy_matrix', ...
            [1, 1, 1, 1; ...
             1, 1, 1, 1; ...
             0, 1, 1, 0; ...
             1, 1, 1, 1; ...
             1, 1, 1, 1], ...
            'cc_row', [3 4], ... %A
            'cc_col', [2 3], ...
            'mc_row', [1 2], ... %1
            'mc_col', 4, ...
            'zf_row', [1 2], ... %2
            'zf_col', 1, ...
            'zy_row', [1 2], ... %4
            'zy_col', 2, ...
            'gy_row', 5, ...     %8
            'gy_col', [1 2], ...
            'hz_row', [1 2], ... %9
            'hz_col', 3, ...
            's1_row', 4, ... %
            's1_col', 1, ...
            's2_row', 4, ... %
            's2_col', 4, ...
            's3_row', 5, ... %
            's3_col', 3, ...
            's4_row', 5, ... %
            's4_col', 4);

        % 烽火不熄
        layout_4 = struct('occupy_matrix', ...
            [1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 0, 0; ...
            1, 1, 1, 1], ...
            'cc_row', [4 5], ... 
            'cc_col', [1 2], ...
            'mc_row', [2 3], ... 
            'mc_col', 1, ...
            'zf_row', [1 2], ... 
            'zf_col', 2, ...
            'zy_row', [1 2], ... 
            'zy_col', 3, ...
            'gy_row', 5, ...     
            'gy_col', [3 4], ...
            'hz_row', [2 3], ... 
            'hz_col', 4, ...
            's1_row', 1, ... 
            's1_col', 1, ...
            's2_row', 1, ... 
            's2_col', 4, ...
            's3_row', 3, ... 
            's3_col', 2, ...
            's4_row', 3, ... 
            's4_col', 3);

%         有勇有谋
layout_5 = struct('occupy_matrix', ...
            [0, 0, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 1, 1], ...
            'cc_row', [2 3], ... 
            'cc_col', [1 2], ...
            'mc_row', [4 5], ... 
            'mc_col', 2, ...
            'zf_row', [2 3], ... 
            'zf_col', 3, ...
            'zy_row', [3 4], ... 
            'zy_col', 4, ...
            'gy_row', 1, ...     
            'gy_col', [3 4], ...
            'hz_row', [4 5], ... 
            'hz_col', 3, ...
            's1_row', 2, ... 
            's1_col', 4, ...
            's2_row', 4, ... 
            's2_col', 1, ...
            's3_row', 5, ... 
            's3_col', 1, ...
            's4_row', 5, ... 
            's4_col', 4);

% 整齐排行
        layout_6 = struct('occupy_matrix', ...
            [1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            0, 0, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 1, 1], ...
            'cc_row', [1 2], ... 
            'cc_col', [2 3], ...
            'mc_row', [4 5], ... 
            'mc_col', 3, ...
            'zf_row', [1 2], ... 
            'zf_col', 1, ...
            'zy_row', [4 5], ... 
            'zy_col', 1, ...
            'gy_row', 3, ...     
            'gy_col', [3 4], ...
            'hz_row', [4 5], ... 
            'hz_col', 4, ...
            's1_row', 1, ... 
            's1_col', 4, ...
            's2_row', 2, ... 
            's2_col', 4, ...
            's3_row', 4, ... 
            's3_col', 2, ...
            's4_row', 5, ... 
            's4_col', 2);

%         屯兵东路
        layout_7 = struct('occupy_matrix', ...
            [1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 0, 0], ...
            'cc_row', [1 2], ... 
            'cc_col', [1 2], ...
            'mc_row', [4 5], ... 
            'mc_col', 2, ...
            'zf_row', [1 2], ... 
            'zf_col', 3, ...
            'zy_row', [1 2], ... 
            'zy_col', 4, ...
            'gy_row', 3, ...     
            'gy_col', [1 2], ...
            'hz_row', [4 5], ... 
            'hz_col', 1, ...
            's1_row', 3, ... 
            's1_col', 3, ...
            's2_row', 3, ... 
            's2_col', 4, ...
            's3_row', 4, ... 
            's3_col', 3, ...
            's4_row', 4, ... 
            's4_col', 4);

%         大呼驰下
        layout_8 = struct('occupy_matrix', ...
            [1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            0, 1, 1, 0; ...
            1, 1, 1, 1; ...
            1, 1, 1, 1], ...
            'cc_row', [1 2], ... 
            'cc_col', [2 3], ...
            'mc_row', [4 5], ... 
            'mc_col', 2, ...
            'zf_row', [4 5], ... 
            'zf_col', 4, ...
            'zy_row', [4 5], ... 
            'zy_col', 1, ...
            'gy_row', 3, ...     
            'gy_col', [2 3], ...
            'hz_row', [4 5], ... 
            'hz_col', 3, ...
            's1_row', 1, ... 
            's1_col', 1, ...
            's2_row', 1, ... 
            's2_col', 4, ...
            's3_row', 2, ... 
            's3_col', 1, ...
            's4_row', 2, ... 
            's4_col', 4);

%         四面楚歌
        layout_9 = struct('occupy_matrix', ...
            [1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            0, 1, 1, 0], ...
            'cc_row', [2 3], ... 
            'cc_col', [2 3], ...
            'mc_row', [3 4], ... 
            'mc_col', 1, ...
            'zf_row', [1 2], ... 
            'zf_col', 1, ...
            'zy_row', [2 3], ... 
            'zy_col', 4, ...
            'gy_row', 1, ...     
            'gy_col', [2 3], ...
            'hz_row', [4 5], ... 
            'hz_col', 2, ...
            's1_row', 1, ... 
            's1_col', 4, ...
            's2_row', 4, ... 
            's2_col', 3, ...
            's3_row', 4, ... 
            's3_col', 4, ...
            's4_row', 5, ... 
            's4_col', 3);
% 一横布局
        layout_10 = struct('occupy_matrix', ...
            [1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            1, 1, 1, 1; ...
            0, 1, 1, 0], ...
            'cc_row', [2 3], ... 
            'cc_col', [2 3], ...
            'mc_row', [3 4], ... 
            'mc_col', 1, ...
            'zf_row', [1 2], ... 
            'zf_col', 1, ...
            'zy_row', [4 5], ... 
            'zy_col', 3, ...
            'gy_row', 1, ...     
            'gy_col', [2 3], ...
            'hz_row', [2 3], ... 
            'hz_col', 4, ...
            's1_row', 1, ... 
            's1_col', 4, ...
            's2_row', 4, ... 
            's2_col', 2, ...
            's3_row', 4, ... 
            's3_col', 4, ...
            's4_row', 5, ... 
            's4_col', 2);
    end

    methods
        function init_game(app)
            %Statement Tc 1.2.2
            updata_step(app, 0);
            app.iswin = 0;

            fields = {'cc', 's1', 's2', 's3', 's4', 'gy', 'zf', 'zy', 'hz', 'mc'};
            num_pieces = length(fields);
            for i = 1:num_pieces
                field = fields{i};
                row_field = strcat(field, '_row');
                col_field = strcat(field, '_col');

                app.game_board.occupy_matrix = app.game_board.initial_matrix;
%                 app.game_board.reset_layout();
                app.(field).Layout.Row = app.game_board.(row_field);
                app.(field).Layout.Column = app.game_board.(col_field);
            end
            
            record_layout(app);
        end

        function reset_game(app)
            %Statement Tc 1.2.3
            updata_step(app, 0);
            app.iswin = 0;

            fields = {'cc', 's1', 's2', 's3', 's4', 'gy', 'zf', 'zy', 'hz', 'mc'};
            for i = 1:length(fields)
                field = fields{i};
                row_field = strcat(field, '_row');
                col_field = strcat(field, '_col');

%                 app.game_board.occupy_matrix = app.game_board.initial_matrix;
                app.game_board.reset_layout();
                app.(field).Layout.Row = app.game_board.(row_field);
                app.(field).Layout.Column = app.game_board.(col_field);
            end

            app.seleted_piece = 0;
            app.history_list = struct('selected_piece', {}, 'dir', {});
            app.undo.Enable = 'off';
        end

        function updata_step(app, indicator)
            switch indicator
                case 1
                    app.step_num = app.step_num + 1;
                case 0
                    app.step_num = 0;
            end
            app.step_count.Text = "步数: " + num2str(app.step_num);
        end

        function piece_moved = move_piece(app, piece, dir)
            piece_moved = false;

            % 检查 piece 是否为空或未定义
            if piece == 0 % branch tc 1.2.5.1
                return
            end
            if isempty(piece) || ~exist('piece', 'var') % branch tc 1.2.5.2
                return;
            end

            switch dir
                case 'u' % branch tc 1.2.5.3
                    is_moved = app.game_board.move_up(piece.Layout.Row, piece.Layout.Column);
                    if is_moved
                        piece.Layout.Row = piece.Layout.Row - 1;
                        piece_moved = true;
                        updata_step(app, 1)
                    end
                case 'd' % branch tc 1.2.5.4
                    is_moved = app.game_board.move_down(piece.Layout.Row, piece.Layout.Column);
                    if is_moved
                        piece.Layout.Row = piece.Layout.Row + 1;
                        piece_moved = true;
                        updata_step(app, 1)
                    end
                case 'l' % branch tc 1.2.5.5
                    is_moved = app.game_board.move_left(piece.Layout.Row, piece.Layout.Column);
                    if is_moved
                        piece.Layout.Column = piece.Layout.Column - 1;
                        piece_moved = true;
                        updata_step(app, 1)
                    end
                case 'r' % branch tc 1.2.5.6
                    is_moved = app.game_board.move_right(piece.Layout.Row, piece.Layout.Column);
                    if is_moved
                        piece.Layout.Column = piece.Layout.Column + 1;
                        piece_moved = true;
                        updata_step(app, 1)
                    end
                otherwise
            end
        end

        function record_move(app, dir)
                new_op.selected_piece = app.seleted_piece;
                new_op.dir = dir;
                if isempty(app.history_list) % branch tc 1.2.6.1
                    app.history_list(1) = new_op;
                    app.undo.Enable = 'on';
                else % branch tc 1.2.6.2
                    app.history_list(end+1) = new_op;
                end
                disp(new_op);
                disp(app.history_list);
        end

        function check_win(app)
            if all(app.cc.Layout.Row == [4 5]) && all(app.cc.Layout.Column == [2 3]) % branch tc 1.2.5.1
                disp('win!');

                % 保存历史记录到 Excel 文件
                filename = strcat('src/records/', datestr(now,'yyyymmdd_HHMMSS'), '.xlsx');
                writematrix(app.layout_list, filename);
                pause(1);

                winui = win(app.step_num, app.game_mode, app.game_board);
                app.game_board.win = winui;
                app.iswin = 1;
                delete(app);
            end
        end

        function record_layout(app)
            % branch tc 1.2.7
%             fields = {'cc', 's1', 's2', 's3', 's4', 'gy', 'zf', 'zy', 'hz', 'mc'};
            % 只有 gy 是横着的，其他将是竖着的

            % 获取当前棋子位置
            new_layout = [
                app.cc.Layout.Row(1), app.cc.Layout.Row(2), ...
                app.cc.Layout.Column(1), app.cc.Layout.Column(2), ...
                app.s1.Layout.Row, app.s1.Layout.Column, ...
                app.s2.Layout.Row, app.s2.Layout.Column, ...
                app.s3.Layout.Row, app.s3.Layout.Column, ...
                app.s4.Layout.Row, app.s4.Layout.Column, ...
                app.gy.Layout.Row, ...
                app.gy.Layout.Column(1), app.gy.Layout.Column(2),...
                app.zf.Layout.Row(1), app.zf.Layout.Row(2), ...
                app.zf.Layout.Column, ...
                app.zy.Layout.Row(1), app.zy.Layout.Row(2), ...
                app.zy.Layout.Column, ...
                app.hz.Layout.Row(1), app.hz.Layout.Row(2), ...
                app.hz.Layout.Column, ...
                app.mc.Layout.Row(1), app.mc.Layout.Row(2), ...
                app.mc.Layout.Column
                ];
      

            % 将当前位置信息加入到历史记录中
            app.layout_list = [app.layout_list; new_layout];

            % 输出当前历史记录
            disp("layout recorded:");
            disp(app.layout_list);

            % 保存历史记录到 Excel 文件
%             filename = strcat('records/', datestr(now,'yyyymmdd_HHMMSS'), '.xlsx');
%             writematrix(app.layout_list, filename);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, game_mode, game_board)
            disp("game mode: " + num2str(game_mode));
            disp("game board: ");
            disp(game_board);
           

            app.game_mode = game_mode;
            app.game_board = game_board;
            
            if game_mode ~= -1 % 非自定义 % Branch Tcover1.1.1
                % pass layout data to controller
                app.game_board.set_layout(app.(sprintf('layout_%d', app.game_mode))); 
            end

            if (game_mode == -1) || (game_mode == 0) % Branch Tc1.1.2
                app.next_level.Enable = 'off';
                app.last_level.Enable = 'off';
            elseif game_mode == 1 % Branch Tc1.1.3
                app.last_level.Enable = 'off';
                app.next_level.Enable = 'on';
            elseif game_mode == 10 % Branch Tc1.1.4
                app.last_level.Enable = 'on';
                app.next_level.Enable = 'off';
            elseif game_mode > 1 && game_mode < 10 % Branch Tc1.1.5
                app.last_level.Enable = 'on';
                app.next_level.Enable = 'on';
            end

            init_game(app);
            check_win(app);
        end

        % Button pushed function: cc
        function ccButtonPushed(app, event)
        app.seleted_piece = app.cc;
        disp("cc selected");
        end

        % Button pushed function: s4
        function s4ButtonPushed(app, event)
        app.seleted_piece = app.s4;
        disp("s4 selected");
        end

        % Button pushed function: s3
        function s3ButtonPushed(app, event)
        app.seleted_piece = app.s3;
        disp("s3 selected");
        end

        % Button pushed function: s2
        function s2ButtonPushed(app, event)
        app.seleted_piece = app.s2;
        disp("s2 selected");
        end

        % Button pushed function: s1
        function s1ButtonPushed(app, event)
        app.seleted_piece = app.s1;
        disp("s1 selected");
        end

        % Button pushed function: gy
        function gyButtonPushed(app, event)
        app.seleted_piece = app.gy;
        disp("gy selected");
        end

        % Button pushed function: mc
        function mcButtonPushed(app, event)
        app.seleted_piece = app.mc;
        disp("mc selected");
        end

        % Button pushed function: zf
        function zfButtonPushed(app, event)
        app.seleted_piece = app.zf;
        disp("zf selected");
        end

        % Button pushed function: zy
        function zyButtonPushed(app, event)
        app.seleted_piece = app.zy;
        disp("zy selected");
        end

        % Button pushed function: hz
        function hzButtonPushed(app, event)
        app.seleted_piece = app.hz;
        disp("hz selected");
        end

        % Button pushed function: down
        function downButtonPushed(app, event)
        if move_piece(app, app.seleted_piece, 'd')
            record_move(app, 'd');
            record_layout(app);
        end
        check_win(app);
        end

        % Button pushed function: right
        function rightButtonPushed(app, event)
        if move_piece(app, app.seleted_piece, 'r')
            record_move(app, 'r');
            record_layout(app);
        end
        check_win(app);
        end

        % Button pushed function: left
        function leftButtonPushed(app, event)
        if move_piece(app, app.seleted_piece, 'l')
            record_move(app, 'l');
            record_layout(app);
        end
        check_win(app);
        end

        % Button pushed function: up
        function upButtonPushed(app, event)
        if move_piece(app, app.seleted_piece, 'u')
            record_move(app, 'u')
            record_layout(app);
        end
        check_win(app);
        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
        reset_game(app);
        end

        % Button pushed function: home
        function homeButtonPushed(app, event)
        gameUi = game_home(app.game_board);
        app.game_board.game = gameUi;
        delete(app);
        end

        % Button pushed function: next_level
        function next_levelButtonPushed(app, event)
            gameui = game(app.game_mode+1, app.game_board);
            app.game_board.game = gameui;
            delete(app);
        end

        % Button pushed function: last_level
        function last_levelButtonPushed(app, event)
            gameui = game(app.game_mode-1, app.game_board);
            app.game_board.game = gameui;
            delete(app);
        end

        % Button pushed function: undo
        function undoButtonPushed(app, event)
            if isempty(app.history_list) % branch tc 1.2.4.1
                return
            end

            op_to_undo = app.history_list(end);
            app.history_list(end) = [];

            if isempty(app.history_list) % branch tc 1.2.4.2
                app.undo.Enable = 'off';
            end

            count_dir = '';
            switch op_to_undo.dir
                case 'u' % branch tc 1.2.4.3
                    count_dir = 'd';
                case 'd' % branch tc 1.2.4.4
                    count_dir = 'u';
                case 'l' % branch tc 1.2.4.5
                    count_dir = 'r';
                case 'r' % branch tc 1.2.4.6
                    count_dir = 'l';
            end

            move_piece(app, op_to_undo.selected_piece, count_dir);
        end

        % Button pushed function: help
        function helpButtonPushed(app, event)
            chessboard = zeros(5, 4);
            chessboard(app.cc.Layout.Row(1), app.cc.Layout.Column(1)) = 1;
            chessboard(app.cc.Layout.Row(1), app.cc.Layout.Column(2)) = 1;
            chessboard(app.cc.Layout.Row(2), app.cc.Layout.Column(1)) = 1;
            chessboard(app.cc.Layout.Row(2), app.cc.Layout.Column(2)) = 1;

            chessboard(app.zf.Layout.Row(1), app.zf.Layout.Column) = 2;
            chessboard(app.zf.Layout.Row(2), app.zf.Layout.Column) = 2;

            chessboard(app.mc.Layout.Row(1), app.mc.Layout.Column) = 3;
            chessboard(app.mc.Layout.Row(2), app.mc.Layout.Column) = 3;

            chessboard(app.zy.Layout.Row(1), app.zy.Layout.Column) = 4;
            chessboard(app.zy.Layout.Row(2), app.zy.Layout.Column) = 4;

            chessboard(app.hz.Layout.Row(1), app.hz.Layout.Column) = 5;
            chessboard(app.hz.Layout.Row(2), app.hz.Layout.Column) = 5;

            chessboard(app.gy.Layout.Row, app.gy.Layout.Column(1)) = 6;
            chessboard(app.gy.Layout.Row, app.gy.Layout.Column(2)) = 6;

            chessboard(app.s1.Layout.Row, app.s1.Layout.Column) = 7;
            chessboard(app.s2.Layout.Row, app.s2.Layout.Column) = 7;
            chessboard(app.s3.Layout.Row, app.s3.Layout.Column) = 7;
            chessboard(app.s4.Layout.Row, app.s4.Layout.Column) = 7;
            
            message = sprintf(['CC: row = {%d, %d}, col = {%d %d}\n' ...
                'ZF2: row = {%d, %d}, col = {%d %d}\n' ...
                'MC3: row = {%d, %d}, col = {%d %d}\n' ...
                'ZY4: row = {%d, %d}, col = {%d %d}\n' ...
                'HZ5: row = {%d, %d}, col = {%d %d}\n' ...
                'GY6: row = {%d, %d}, col = {%d %d}\n' ...
                'S1: row = {%d, %d}, col = {%d %d}\n' ...
                'S2: row = {%d, %d}, col = {%d %d}\n' ...
                'S3: row = {%d, %d}, col = {%d %d}\n' ...
                'S4: row = {%d, %d}, col = {%d %d}\n' ...
                'chess:{\n' ...
                '{%d, %d, %d, %d}, \n' ...
                '{%d, %d, %d, %d}, \n' ...
                '{%d, %d, %d, %d}, \n' ...
                '{%d, %d, %d, %d}, \n' ...
                '{%d, %d, %d, %d}}'], ...
                app.cc.Layout.Row(1)-1, app.cc.Layout.Row(2)-1, app.cc.Layout.Column(1)-1, app.cc.Layout.Column(2)-1, ...
                app.zf.Layout.Row(1)-1, app.zf.Layout.Row(2)-1, app.zf.Layout.Column-1, app.zf.Layout.Column-1, ...
                app.mc.Layout.Row(1)-1, app.mc.Layout.Row(2)-1, app.mc.Layout.Column-1, app.mc.Layout.Column-1, ...
                app.zy.Layout.Row(1)-1, app.zy.Layout.Row(2)-1, app.zy.Layout.Column-1, app.zy.Layout.Column-1, ...
                app.hz.Layout.Row(1)-1, app.hz.Layout.Row(2)-1, app.hz.Layout.Column-1, app.hz.Layout.Column-1, ...
                app.gy.Layout.Row-1, app.gy.Layout.Row-1, app.gy.Layout.Column(1)-1, app.gy.Layout.Column(2)-1, ...
                app.s1.Layout.Row-1, app.s1.Layout.Row-1, app.s1.Layout.Column-1, app.s1.Layout.Column-1, ...
                app.s2.Layout.Row-1, app.s2.Layout.Row-1, app.s2.Layout.Column-1, app.s2.Layout.Column-1, ...
                app.s3.Layout.Row-1, app.s3.Layout.Row-1, app.s3.Layout.Column-1, app.s3.Layout.Column-1, ...
                app.s4.Layout.Row-1, app.s4.Layout.Row-1, app.s4.Layout.Column-1, app.s4.Layout.Column-1, ...
                chessboard(1,1), chessboard(1,2), chessboard(1,3), chessboard(1,4), ...
                chessboard(2,1), chessboard(2,2), chessboard(2,3), chessboard(2,4), ...
                chessboard(3,1), chessboard(3,2), chessboard(3,3), chessboard(3,4), ...
                chessboard(4,1), chessboard(4,2), chessboard(4,3), chessboard(4,4), ...
                chessboard(5,1), chessboard(5,2), chessboard(5,3), chessboard(5,4));
            title = '提示';
            msgbox(message, title);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlayUIFigure = uifigure('Visible', 'off');
            app.PlayUIFigure.Name = 'Play';
            app.GamingPanel = uipanel(app.PlayUIFigure);
            app.GamingPanel.Title = 'Gaming';
            app.Label_4 = uilabel(app.GamingPanel);
            app.Label_4.Text = '';
            app.GamePanel = uipanel(app.GamingPanel);
            app.GamePanel.Title = 'Game Panel';
            app.GridLayout = uigridlayout(app.GamePanel);
            app.hz = uibutton(app.GridLayout, 'push');
            app.hz.ButtonPushedFcn = createCallbackFcn(app, @hzButtonPushed, true);
            app.hz.Text = '黄忠';
            app.zy = uibutton(app.GridLayout, 'push');
            app.zy.ButtonPushedFcn = createCallbackFcn(app, @zyButtonPushed, true);
            app.zy.Text = '赵云';
            app.zf = uibutton(app.GridLayout, 'push');
            app.zf.ButtonPushedFcn = createCallbackFcn(app, @zfButtonPushed, true);
            app.zf.Text = '张飞';
            app.mc = uibutton(app.GridLayout, 'push');
            app.mc.ButtonPushedFcn = createCallbackFcn(app, @mcButtonPushed, true);
            app.mc.Text = '马超';
            app.gy = uibutton(app.GridLayout, 'push');
            app.gy.ButtonPushedFcn = createCallbackFcn(app, @gyButtonPushed, true);
            app.gy.Text = '关羽';
            app.s1 = uibutton(app.GridLayout, 'push');
            app.s1.ButtonPushedFcn = createCallbackFcn(app, @s1ButtonPushed, true);
            app.s1.Text = '卒';
            app.s2 = uibutton(app.GridLayout, 'push');
            app.s2.ButtonPushedFcn = createCallbackFcn(app, @s2ButtonPushed, true);
            app.s2.Text = '卒';
            app.s3 = uibutton(app.GridLayout, 'push');
            app.s3.ButtonPushedFcn = createCallbackFcn(app, @s3ButtonPushed, true);
            app.s3.Text = '卒';
            app.s4 = uibutton(app.GridLayout, 'push');
            app.s4.ButtonPushedFcn = createCallbackFcn(app, @s4ButtonPushed, true);
            app.s4.Text = '卒';
            app.cc = uibutton(app.GridLayout, 'push');
            app.cc.ButtonPushedFcn = createCallbackFcn(app, @ccButtonPushed, true);
            app.cc.Text = ' 曹操';
            app.home = uibutton(app.GamingPanel, 'push');
            app.home.ButtonPushedFcn = createCallbackFcn(app, @homeButtonPushed, true);
            app.home.Text = '返回主菜单';
            app.reset = uibutton(app.GamingPanel, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Text = '重置';
            app.undo = uibutton(app.GamingPanel, 'push');
            app.undo.ButtonPushedFcn = createCallbackFcn(app, @undoButtonPushed, true);
            app.undo.Enable = 'off';
            app.undo.Text = '回退';
            app.last_level = uibutton(app.GamingPanel, 'push');
            app.last_level.ButtonPushedFcn = createCallbackFcn(app, @last_levelButtonPushed, true);
            app.last_level.Enable = 'off';
            app.last_level.Text = '上一关';
            app.next_level = uibutton(app.GamingPanel, 'push');
            app.next_level.ButtonPushedFcn = createCallbackFcn(app, @next_levelButtonPushed, true);
            app.next_level.Enable = 'off';
            app.next_level.Text = '下一关';
            app.up = uibutton(app.GamingPanel, 'push');
            app.up.ButtonPushedFcn = createCallbackFcn(app, @upButtonPushed, true);
            app.up.Text = '上';
            app.left = uibutton(app.GamingPanel, 'push');
            app.left.ButtonPushedFcn = createCallbackFcn(app, @leftButtonPushed, true);
            app.left.Text = '左';
            app.right = uibutton(app.GamingPanel, 'push');
            app.right.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.right.Text = '右';
            app.down = uibutton(app.GamingPanel, 'push');
            app.down.ButtonPushedFcn = createCallbackFcn(app, @downButtonPushed, true);
            app.down.Text = '下';
            app.step_count = uilabel(app.GamingPanel);
            app.step_count.Text = '步数: 0';
            app.level_name = uilabel(app.GamingPanel);
            app.level_name.Visible = 'off';
            app.level_name.Text = '层层设防二';
            app.help = uibutton(app.GamingPanel, 'push');
            app.help.ButtonPushedFcn = createCallbackFcn(app, @helpButtonPushed, true);
            app.help.Text = '帮助';
            % Show the figure after all components are created
            app.PlayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlayUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlayUIFigure)
        end
    end
end