classdef game_history < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HistoryUIFigure  matlab.ui.Figure
        Panel            matlab.ui.container.Panel
        review           matlab.ui.control.Button
        ListBox          matlab.ui.control.ListBox
        home             matlab.ui.control.Button
    end


    properties (Access = private)
        % @property {counter} controller: Controller for the counter
    end    
    
    properties (Access = public)
        selected_history; % Description
        game_board;
    end
    
    methods (Access = public)
        
        % @contructor
        % @param {counter} controller: Controller for the counter
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, game_board)
            app.game_board = game_board;
            app.ListBox.Items = string({dir('./src/records/*.xlsx').name});
            if isempty(app.ListBox.Items)
                app.review.Enable = 'off';
            end
            app.selected_history = "./src/records/" + app.ListBox.Value;
        end

        % Button pushed function: review
        function reviewButtonPushed(app, event)
            disp(app.selected_history);
            reviewui = game_review(app.game_board, app.selected_history);
            app.game_board.review = reviewui;
            delete(app);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.selected_history = value;
        end

        % Button pushed function: home
        function homeButtonPushed(app, event)
            game_home(app.game_board);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HistoryUIFigure = uifigure('Visible', 'off');
            app.HistoryUIFigure.Name = 'History';
            app.Panel = uipanel(app.HistoryUIFigure);
            app.Panel.Title = '历史记录';
            app.home = uibutton(app.Panel, 'push');
            app.home.ButtonPushedFcn = createCallbackFcn(app, @homeButtonPushed, true);
            app.home.Text = '返回主页';
            app.ListBox = uilistbox(app.Panel);
            app.ListBox.Items = {'1', '2', '3', '4'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '1';
            app.review = uibutton(app.Panel, 'push');
            app.review.ButtonPushedFcn = createCallbackFcn(app, @reviewButtonPushed, true);
            app.review.Text = '回顾';
            % Show the figure after all components are created
            app.HistoryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game_history(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HistoryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HistoryUIFigure)
        end
    end
end