classdef game_home < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Panel      matlab.ui.container.Panel
        Image      matlab.ui.control.Image
        DropDown   matlab.ui.control.DropDown
        Label_2    matlab.ui.control.Label
        exit       matlab.ui.control.Button
        history    matlab.ui.control.Button
        customize  matlab.ui.control.Button
        start      matlab.ui.control.Button
        Label      matlab.ui.control.Label
    end

    
    properties (Access = private)
        game_mode = 1; % 关卡编号，默认为 1
        game_board;
    end    
    
    methods (Access = public)
        
        % @contructor
        % @param {counter} controller: Controller for the counter
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, board)
            app.game_mode = 1;
            app.game_board = board;
            disp("game launched");
        end

        % Value changed function: DropDown
        function modeChangeHandler(app, event)
            switch app.DropDown.Value
                case 'win test'
                    app.game_mode = 0;
                    app.Image.ImageSource = 'src/assets/win_test.png';
                case '连战连捷'
                    app.game_mode = 1;
                    app.Image.ImageSource = 'src/assets/连战连捷.png';
                    %                     disp(app.game_mode)
                case '身先士卒'
                    app.game_mode = 2;
                    app.Image.ImageSource = 'src/assets/身先士卒.png';
                case '一夫当关'
                    app.game_mode = 3;
                    app.Image.ImageSource = 'src/assets/一夫当关.png';
                case '烽火不熄'
                    app.game_mode = 4;
                    app.Image.ImageSource = 'src/assets/烽火不熄.png';
                case '有勇有谋'
                    app.game_mode = 5;
                    app.Image.ImageSource = 'src/assets/有勇有谋.png';
                case '整齐排行'
                    app.game_mode = 6;
                    app.Image.ImageSource = 'src/assets/整齐排行.png';
                case '屯兵东路'
                    app.game_mode = 7;
                    app.Image.ImageSource = 'src/assets/屯兵东路.png';
                case '大呼驰下'
                    app.game_mode = 8;
                    app.Image.ImageSource = 'src/assets/大呼驰下.png';
                case '四面楚歌'
                    app.game_mode = 9;
                    app.Image.ImageSource = 'src/assets/四面楚歌.png';
                case '一横布局'
                    app.game_mode = 10;
                    app.Image.ImageSource = 'src/assets/一横布局.png';
                case '随机关卡'
                    app.game_mode = randi([1, 10]);
                    app.Image.ImageSource = 'src/assets/random.png';
                otherwise
            end
        end

        % Button pushed function: start
        function startButtonPushed(app, event)
            gameUI = game(app.game_mode, app.game_board);
            app.game_board.game = gameUI;
            delete(app);
        end

        % Button pushed function: exit
        function exitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: customize
        function customizeButtonPushed(app, event)
            cui = customize_game(app.game_board);
            app.game_board.customize = cui;
            delete(app);
        end

        % Button pushed function: history
        function historyButtonPushed(app, event)
            hisui = game_history(app.game_board);
            app.game_board.history = hisui;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '华容道';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '主界面';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '华容道';
            app.start = uibutton(app.Panel, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = '开始游戏';
            app.customize = uibutton(app.Panel, 'push');
            app.customize.ButtonPushedFcn = createCallbackFcn(app, @customizeButtonPushed, true);
            app.customize.Text = '自定义游戏';
            app.history = uibutton(app.Panel, 'push');
            app.history.ButtonPushedFcn = createCallbackFcn(app, @historyButtonPushed, true);
            app.history.Text = '历史记录';
            app.exit = uibutton(app.Panel, 'push');
            app.exit.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exit.Text = '退出';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = {'关卡选择'; ''};
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'win test', '连战连捷', '身先士卒', '一夫当关', '烽火不熄', '有勇有谋', '整齐排行', '屯兵东路', '大呼驰下', '四面楚歌', '一横布局', '随机关卡'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @modeChangeHandler, true);
            app.DropDown.Value = '连战连捷';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = '连战连捷.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game_home(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end