classdef win < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Panel         matlab.ui.container.Panel
        step_label    matlab.ui.control.Label
        exit_game     matlab.ui.control.Button
        restart_game  matlab.ui.control.Button
        home          matlab.ui.control.Button
        Label         matlab.ui.control.Label
    end


    properties (Access = public)
        % @property {counter} controller: Controller for the counter
        game_mode;
        game_board;
    end    
    
    methods (Access = public)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, step_num, game_mode, game_board)
            app.step_label.Text = "步数: " + num2str(step_num);
            app.game_mode = game_mode;
            app.game_board = game_board;
        end

        % Button pushed function: exit_game
        function exit_gameButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: home
        function homeButtonPushed(app, event)
            homeui = game_home(app.game_board);
            app.game_board.home = homeui;
            delete(app);    
        end

        % Button pushed function: restart_game
        function restart_gameButtonPushed(app, event)
            gameui = game(app.game_mode, app.game_board);
            app.game_board.game = gameui;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '华容道';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '结束界面';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '恭喜通关！';
            app.home = uibutton(app.Panel, 'push');
            app.home.ButtonPushedFcn = createCallbackFcn(app, @homeButtonPushed, true);
            app.home.Text = '返回主页';
            app.restart_game = uibutton(app.Panel, 'push');
            app.restart_game.ButtonPushedFcn = createCallbackFcn(app, @restart_gameButtonPushed, true);
            app.restart_game.Text = '重新开始';
            app.exit_game = uibutton(app.Panel, 'push');
            app.exit_game.ButtonPushedFcn = createCallbackFcn(app, @exit_gameButtonPushed, true);
            app.exit_game.Text = '退出游戏';
            app.step_label = uilabel(app.Panel);
            app.step_label.Text = '步数：50';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = win(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end