classdef game_review < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReviewUIFigure  matlab.ui.Figure
        Panel           matlab.ui.container.Panel
        step_count      matlab.ui.control.Label
        label           matlab.ui.control.Label
        last_layout     matlab.ui.control.Button
        next_layout     matlab.ui.control.Button
        home            matlab.ui.control.Button
        GamePanel       matlab.ui.container.Panel
        GridLayout      matlab.ui.container.GridLayout
        cc              matlab.ui.control.Button
        s4              matlab.ui.control.Button
        s3              matlab.ui.control.Button
        s2              matlab.ui.control.Button
        s1              matlab.ui.control.Button
        gy              matlab.ui.control.Button
        mc              matlab.ui.control.Button
        zf              matlab.ui.control.Button
        zy              matlab.ui.control.Button
        hz              matlab.ui.control.Button
    end


    properties (Access = private)
        % @property {counter} controller: Controller for the counter
    end    
    
    properties (Access = public)
        game_board; % Description
        current_layout_num = 1; % Description
        layout_list; % Description
        layout_num;
    end
    
    methods (Access = public)
        
        % @contructor
        % @param {counter} controller: Controller for the counter
        
        function update_layout(app)
            layout = app.layout_list(app.current_layout_num, :);
            disp('review: ')
            disp(layout);
            app.cc.Layout.Row= [layout(1) layout(2)];
            app.cc.Layout.Column = [layout(3) layout(4)];

            app.s1.Layout.Row = layout(5);
            app.s1.Layout.Column = layout(6);
            app.s2.Layout.Row = layout(7);
            app.s2.Layout.Column = layout(8);
            app.s3.Layout.Row = layout(9);
            app.s3.Layout.Column = layout(10);
            app.s4.Layout.Row = layout(11);
            app.s4.Layout.Column = layout(12);

            app.gy.Layout.Row = layout(13);
            app.gy.Layout.Column = [layout(14) layout(15)];

            app.zf.Layout.Row = [layout(16) layout(17)];
            app.zf.Layout.Column = layout(18);

            app.zy.Layout.Row = [layout(19) layout(20)];
            app.zy.Layout.Column = layout(21);
            
            app.hz.Layout.Row = [layout(22) layout(23)];
            app.hz.Layout.Column = layout(24);

            app.mc.Layout.Row = [layout(25) layout(26)];
            app.mc.Layout.Column = layout(27);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, board, history_name)
            app.game_board = board;
            app.layout_list = readmatrix(history_name);
            app.layout_num = size(app.layout_list, 1);
            disp("layout_list: ")
            disp(app.layout_list);
            disp("layout_num: ")
            disp(app.layout_num);
            app.current_layout_num = 1;

            update_layout(app);
        end

        % Button pushed function: last_layout
        function last_layoutButtonPushed(app, event)
            app.current_layout_num = app.current_layout_num - 1;
            update_layout(app);
            
            if app.current_layout_num == 1 % Branch Tcover 1.4.1.1
                app.last_layout.Enable = 'off';
            end

            if app.current_layout_num ~= app.layout_num % Branch Tcover 1.4.1.2
                app.next_layout.Enable = 'on';
            end

            app.step_count.Text = "步数: " + num2str(app.current_layout_num-1);
        end

        % Button pushed function: next_layout
        function next_layoutButtonPushed(app, event)
            app.current_layout_num = app.current_layout_num + 1;
            update_layout(app);

            if app.current_layout_num == app.layout_num % Branch Tcover 1.4.2.1
                app.next_layout.Enable = 'off';
            end
 
            if app.current_layout_num ~= 1 % Branch Tcover 1.4.2.2
                app.last_layout.Enable = 'on';
            end

            app.step_count.Text = "步数: " + num2str(app.current_layout_num-1);
        end

        % Button pushed function: home
        function homeButtonPushed(app, event)
            homeui = game_home(app.game_board);
            app.game_board.home = homeui;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReviewUIFigure = uifigure('Visible', 'off');
            app.ReviewUIFigure.Name = 'Review';
            app.Panel = uipanel(app.ReviewUIFigure);
            app.Panel.Title = '历史记录';
            app.GamePanel = uipanel(app.Panel);
            app.GamePanel.Title = 'Game Panel';
            app.GridLayout = uigridlayout(app.GamePanel);
            app.hz = uibutton(app.GridLayout, 'push');
            app.hz.Text = '黄忠';
            app.zy = uibutton(app.GridLayout, 'push');
            app.zy.Text = '赵云';
            app.zf = uibutton(app.GridLayout, 'push');
            app.zf.Text = '张飞';
            app.mc = uibutton(app.GridLayout, 'push');
            app.mc.Text = '马超';
            app.gy = uibutton(app.GridLayout, 'push');
            app.gy.Text = '关羽';
            app.s1 = uibutton(app.GridLayout, 'push');
            app.s1.Text = '卒';
            app.s2 = uibutton(app.GridLayout, 'push');
            app.s2.Text = '卒';
            app.s3 = uibutton(app.GridLayout, 'push');
            app.s3.Text = '卒';
            app.s4 = uibutton(app.GridLayout, 'push');
            app.s4.Text = '卒';
            app.cc = uibutton(app.GridLayout, 'push');
            app.cc.Text = ' 曹操';
            app.home = uibutton(app.Panel, 'push');
            app.home.ButtonPushedFcn = createCallbackFcn(app, @homeButtonPushed, true);
            app.home.Text = '返回主菜单';
            app.next_layout = uibutton(app.Panel, 'push');
            app.next_layout.ButtonPushedFcn = createCallbackFcn(app, @next_layoutButtonPushed, true);
            app.next_layout.Text = '下一步';
            app.last_layout = uibutton(app.Panel, 'push');
            app.last_layout.ButtonPushedFcn = createCallbackFcn(app, @last_layoutButtonPushed, true);
            app.last_layout.Enable = 'off';
            app.last_layout.Text = '上一步';
            app.label = uilabel(app.Panel);
            app.label.Text = '关卡回顾';
            app.step_count = uilabel(app.Panel);
            app.step_count.Text = '步数: 0';
            % Show the figure after all components are created
            app.ReviewUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game_review(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReviewUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReviewUIFigure)
        end
    end
end