classdef Login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoginUIUIFigure  matlab.ui.Figure
        Image3           matlab.ui.control.Image
        Image2_2         matlab.ui.control.Image
        Image2           matlab.ui.control.Image
        TextArea_2       matlab.ui.control.TextArea
        TextArea         matlab.ui.control.TextArea
        Button           matlab.ui.control.Button
        Image4           matlab.ui.control.Image
    end

    
    properties (Access = public)
        userDB
        train_db
        time_backend
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonMinusPushed(app, event)
           id_ = cell2mat(app.TextArea.Value);
           password_ = cell2mat(app.TextArea_2.Value);
           if app.userDB.login(id_, password_)
               user = user_backend(id_,app.train_db,app.time_backend);
           else
               msgbox('该账号已经被登录或者账号、密码不正确', '错误');
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoginUIUIFigure = uifigure('Visible', 'off');
            app.LoginUIUIFigure.Name = 'Login UI';
            app.Image4 = uiimage(app.LoginUIUIFigure);
            app.Image4.ImageSource = 'login_background.png';
            app.Button = uibutton(app.LoginUIUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusPushed, true);
            app.Button.Text = '登录';
            app.TextArea = uitextarea(app.LoginUIUIFigure);
            app.TextArea_2 = uitextarea(app.LoginUIUIFigure);
            app.Image2 = uiimage(app.LoginUIUIFigure);
            app.Image2.ImageSource = 'login-ID.png';
            app.Image2_2 = uiimage(app.LoginUIUIFigure);
            app.Image2_2.ImageSource = '登录-密码.png';
            app.Image3 = uiimage(app.LoginUIUIFigure);
            app.Image3.ImageSource = '12306.png';
            % Show the figure after all components are created
            app.LoginUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoginUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoginUIUIFigure)
        end
    end
end