classdef Order < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Label_13        matlab.ui.control.Label
        Label_12        matlab.ui.control.Label
        price           matlab.ui.control.TextArea
        Label_11        matlab.ui.control.Label
        arrive_time     matlab.ui.control.TextArea
        Label_10        matlab.ui.control.Label
        start_time      matlab.ui.control.TextArea
        Label_9         matlab.ui.control.Label
        TextArea        matlab.ui.control.TextArea
        ID              matlab.ui.control.Label
        Label_6         matlab.ui.control.Label
        Button_2        matlab.ui.control.Button
        Button          matlab.ui.control.Button
        arrive_station  matlab.ui.control.Label
        start_station   matlab.ui.control.Label
        Label           matlab.ui.control.Label
        Image           matlab.ui.control.Image
    end

    
    properties (Access = public)
        backend % Description
        traindb
        s_plan %当前列车计划
        now_plan
        time
        time_period
        Clock
        time_deadline
    end
    
    methods (Access = public)
%                                 single_non_stop_plan = {train.train_id %火车编号
%                                     '无'%中转站
%                                     train.stops{start_index,3} %出发时间
%                                     train.stops{end_index,2} %到达时间
%                                     num2str(num_available_table) %余票
%                                     num2str(price)%价格
%                                     0 %表示直达
%                                     train %该直达火车
%                                     start_index %出发站
%                                     end_index %到达站
%                                     };
%                                             intermediate_single_plan = {ID %火车编号
%                                                 first_train.stops{m,1} %中转站
%                                                 depature_time %出发时间
%                                                 arrive_time %到达时间
%                                                 num2str(num_available_table) %余票
%                                                 num2str(price)%价格
%                                                 1 %表示直达
%                                                 first_train %第一火车
%                                                 second_train %第二火车
%                                                 start_index %出发站
%                                                 end_index %到达站
%                                                 }; 
        function show(app,single_plan)
            app.s_plan = single_plan;
            app.ID.Text = single_plan{1};
            app.price.Value = [single_plan{6},'¥'];
            if single_plan{7} == 0
                start_index = single_plan{9};
                end_index = single_plan{10};
                app.start_station.Text = single_plan{8}.stops{start_index,1};
                app.start_time.Value = single_plan{8}.stops{start_index,3};
                app.arrive_station.Text = single_plan{8}.stops{end_index,1};
                app.arrive_time.Value = single_plan{8}.stops{end_index,2};
            else
                start_index = single_plan{10};
                end_index = single_plan{11}; 
                app.start_station.Text = single_plan{8}.stops{start_index,1};
                app.start_time.Value = single_plan{8}.stops{start_index,3};
                app.arrive_station.Text = single_plan{9}.stops{end_index,1};
                app.arrive_time.Value = single_plan{9}.stops{end_index,2};               
            end
        end
        
        function TimerCallBackFunction(app, ~, ~)
            app.time_period = app.backend.Time_backend.time_period;
            if app.backend.Time_backend.UI.Button.Value
                app.Clock = app.Clock - seconds(app.time_period);
                if(app.Clock <= app.time_deadline)
                    app.backend.table_manage_back(app.s_plan);
                    msgbox('支付时间到，支付失败', '提示');
                    train_show = Train; %创建一个列车展示界面
                    train_show.UIFigure.Name = strcat(app.backend.user_id,' Ticket List UI');
                    set(train_show.UIFigure, 'Visible', 'off'); %暂时隐藏列车展示界面
                    train_show.traindb = app.traindb; %火车数据库
                    train_show.backend = app.backend;
                    app.backend.TrainUI = train_show;
                    train_show.show_train(app.now_plan);            
                    set(train_show.UIFigure, 'Visible', 'on');
                    stop(app.time);
                    delete(app.time);
                    close(app.UIFigure);
                end
            end

            app.TextArea.Value = char(app.Clock);
        end
        
        function timer_initilize(app)
            app.time_period = app.backend.Time_backend.time_period;
            app.time = timer;
            app.time.TimerFcn = @app.TimerCallBackFunction;
            app.time.ExecutionMod = 'fixedSpacing';
            app.time.Period = 1;
            app.Clock = datetime('01:30','format','mm:ss');
            start(app.time);  
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.time_deadline = datetime('00:00','format','mm:ss');
            %app.UIFigure.Name = strcat(app.backend.user_id,'的车票支付界面');
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

            stop(app.time);
            delete(app.time);
            queryUI = Query;
            queryUI.UIFigure.Name = strcat(app.backend.user_id,' Query Ticket UI');
            queryUI.traindb = app.traindb;
            queryUI.backend = app.backend;
            app.backend.QueryUI = queryUI;
            close(app.UIFigure);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.backend.table_manage_back(app.s_plan);
            train_show = Train; %创建一个列车展示界面
            train_show.UIFigure.Name = strcat(app.backend.user_id,' Ticket List UI');
            set(train_show.UIFigure, 'Visible', 'off'); %暂时隐藏列车展示界面
            train_show.traindb = app.traindb; %火车数据库
            train_show.backend = app.backend;
            app.backend.TrainUI = train_show;
            train_show.show_train(app.now_plan);            
            set(train_show.UIFigure, 'Visible', 'on');
            stop(app.time);
            delete(app.time);            
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Order_background.jpg';
            app.Label = uilabel(app.UIFigure);
            app.Label.Interpreter = 'html';
            app.Label.Text = '待支付';
            app.start_station = uilabel(app.UIFigure);
            app.start_station.Interpreter = 'html';
            app.start_station.Text = '上海虹桥';
            app.arrive_station = uilabel(app.UIFigure);
            app.arrive_station.Interpreter = 'html';
            app.arrive_station.Text = '南京南';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '支付订单';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '取消订单';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.ID = uilabel(app.UIFigure);
            app.ID.Text = 'G11';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'01:30'};
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '出发时间';
            app.start_time = uitextarea(app.UIFigure);
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '到达时间';
            app.arrive_time = uitextarea(app.UIFigure);
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '价格';
            app.price = uitextarea(app.UIFigure);
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '已为您锁定车票，请在';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '时间内支付';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Order

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end