classdef Query < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        TabGroup    matlab.ui.container.TabGroup
        Tab_2       matlab.ui.container.Tab
        Button_5    matlab.ui.control.StateButton
        Image3      matlab.ui.control.Image
        Image       matlab.ui.control.Image
        Button_4    matlab.ui.control.Button
        DropDown_4  matlab.ui.control.DropDown
        DropDown_3  matlab.ui.control.DropDown
        Image2      matlab.ui.control.Image
    end

    
    properties (Access = public)
        time
        traindb % Description
        train_valuable
        train_end_valuable
        train_show
        available_plan
        backend
    end
    
    methods (Access = public)
        
        function find_train(app,start,ending)

            app.time = app.backend.Time_backend.Clock;
            index = 0;
            for i = 1:length(app.traindb.TrainList)
                train = app.traindb.TrainList(i);
                if train.has_start_City(start)
                    app.train_valuable.register(train);
                end
            end
            for i = 1:length(app.traindb.TrainList)
                train = app.traindb.TrainList(i);
                if train.has_end_City(ending)
                    app.train_end_valuable.register(train);
                end
            end
            % 直达火车的搜索
            five_minutes = duration(00,05,00);
            train_nonstop = {};
            app.available_plan = {};
            for i = 1:length(app.train_valuable.TrainList)
                for j = 1:length(app.train_end_valuable.TrainList)
                    if app.train_valuable.TrainList(i).train_id == app.train_end_valuable.TrainList(j).train_id
                        train = app.train_valuable.TrainList(i);
                        start_index = train.find_start(start);
                        end_index = train.find_end(ending);
                        start_time = datetime(train.stops{start_index,3},'InputFormat','HH:mm');
                        arrive_time = datetime(train.stops{end_index,2},'InputFormat','HH:mm');
                        if arrive_time > start_time %保证到达时间比出发时间晚
                            if start_time - app.time >= five_minutes %保证出发前5min查到票
                                num_available_table = train.Find_num_table(start_index,end_index);%余票
                                price  = train.price * (end_index - start_index); %价格
                                single_non_stop_plan = {train.train_id %火车编号
                                    '无'%中转站
                                    train.stops{start_index,3} %出发时间
                                    train.stops{end_index,2} %到达时间
                                    num2str(num_available_table) %余票
                                    num2str(price)%价格
                                    0 %表示直达
                                    train %该直达火车
                                    start_index %出发站
                                    end_index %到达站
                                    }; 
                                app.available_plan{end+1} = single_non_stop_plan;
                                index = index + 1;
                                train_nonstop{end+1} = app.train_valuable.TrainList(i);

                            end
                        end
                    end
                end
            end
            for i = 1:length(train_nonstop)
                app.train_valuable.deleteTrain(train_nonstop{i}); %删掉可以直达的车辆
                app.train_end_valuable.deleteTrain(train_nonstop{i}); %删掉可以直达的车辆
            end
            
            % 中转火车的搜索
            for i = 1:length(app.train_valuable.TrainList)
                first_train = app.train_valuable.TrainList(i);
                for m = first_train.find_start(start)+1 : size(first_train.stops,1)
                    for j = 1:length(app.train_end_valuable.TrainList)
                        second_train = app.train_end_valuable.TrainList(j);
                        if first_train.train_id ~= second_train.train_id
                            for n = 1 : second_train.find_end(ending)-1
                                if contains(first_train.stops{m,1},second_train.stops{n,1})
                                    intermediate_arrive_time = datetime(first_train.stops{m,2},'InputFormat','HH:mm');  
                                    intermediate_departure_time = datetime(second_train.stops{n,3},'InputFormat','HH:mm');
                                    if intermediate_arrive_time < intermediate_departure_time %保证中转站出发时间在到达时间之后（有时间段控制？）
                                        depature_time = first_train.Find_depature_Time(start); 
                                        data_depature_time = datetime(depature_time,'InputFormat','HH:mm');
                                        if data_depature_time - app.time >= five_minutes %保证出发前5min查到票
                                            arrive_time = second_train.Find_arrive_Time(ending);
                                            ID = strcat(first_train.train_id,'中转',second_train.train_id);
                                            start_index = first_train.find_start(start);
                                            end_index = second_train.find_end(ending);
                                            num_available_table1 = first_train.Find_num_table(start_index,m);%余票1
                                            num_available_table2 = second_train.Find_num_table(n,end_index);%余票1
                                            if num_available_table2 < num_available_table1
                                                num_available_table = num_available_table2;
                                            else
                                                num_available_table = num_available_table1;
                                            end %选择我两辆车中余票少的
                                            price = (m - start_index) * first_train.price + (end_index - n) * second_train.price;
                                            intermediate_single_plan = {ID %火车编号
                                                first_train.stops{m,1} %中转站
                                                depature_time %出发时间
                                                arrive_time %到达时间
                                                num2str(num_available_table) %余票
                                                num2str(price)%价格
                                                1 %表示直达
                                                first_train %第一火车
                                                second_train %第二火车
                                                start_index %出发站
                                                end_index %到达站
                                                }; 
                                            app.available_plan{end+1} = intermediate_single_plan;
                                            index = index +1;
                                        end

                                    end
                                end
                            end
                        end
                    end
                end
            end

            if index == 0
                msgbox('未找到匹配的车次', '提示');
                set(app.UIFigure,'Visible', 'off'); %暂时隐藏查询界面
                queryUI = Query; %重新打开一个界面，初始化参数
                queryUI.UIFigure.Name = strcat(app.backend.user_id,' Query Ticket UI');
                queryUI.traindb = app.traindb;
                queryUI.backend = app.backend;
                app.backend.QueryUI = queryUI;
                close(app.UIFigure);

            else
                app.train_show = Train; %创建一个列车展示界面
                app.train_show.UIFigure.Name = strcat(app.backend.user_id,' Ticket List UI');
                set(app.train_show.UIFigure, 'Visible', 'off'); %暂时隐藏列车展示界面
                app.train_show.traindb = app.traindb; %火车数据库
                app.train_show.backend = app.backend;
                app.backend.TrainUI = app.train_show;
                app.train_show.show_train(app.available_plan);
                set(app.UIFigure, 'Visible', 'off');
                set(app.train_show.UIFigure, 'Visible', 'on');
                close(app.UIFigure);
            end          
        end

        function find_train_except(app,start,ending,single_ticket)

            app.time = app.backend.Time_backend.Clock;
            index = 0;
            for i = 1:length(app.traindb.TrainList)
                train = app.traindb.TrainList(i);
                if (train.has_start_City(start))&&(~strcmp(train.train_id,single_ticket{1}))
                    app.train_valuable.register(train);
                end
            end
            for i = 1:length(app.traindb.TrainList)
                train = app.traindb.TrainList(i);
                if (train.has_end_City(ending)) &&(~strcmp(train.train_id,single_ticket{1}))
                    app.train_end_valuable.register(train);
                end
            end
            % 直达火车的搜索
            five_minutes = duration(00,05,00);
            train_nonstop = {};
            app.available_plan = {};
            for i = 1:length(app.train_valuable.TrainList)
                for j = 1:length(app.train_end_valuable.TrainList)
                    if app.train_valuable.TrainList(i).train_id == app.train_end_valuable.TrainList(j).train_id
                        train = app.train_valuable.TrainList(i);
                        start_index = train.find_start(start);
                        end_index = train.find_end(ending);
                        start_time = datetime(train.stops{start_index,3},'InputFormat','HH:mm');
                        arrive_time = datetime(train.stops{end_index,2},'InputFormat','HH:mm');
                        if arrive_time > start_time %保证到达时间比出发时间晚
                            if start_time - app.time >= five_minutes %保证出发前5min查到票
                                num_available_table = train.Find_num_table(start_index,end_index);%余票
                                price  = train.price * (end_index - start_index); %价格
                                single_non_stop_plan = {train.train_id %火车编号
                                    '无'%中转站
                                    train.stops{start_index,3} %出发时间
                                    train.stops{end_index,2} %到达时间
                                    num2str(num_available_table) %余票
                                    num2str(price)%价格
                                    0 %表示直达
                                    train %该直达火车
                                    start_index %出发站
                                    end_index %到达站
                                    }; 
                                app.available_plan{end+1} = single_non_stop_plan;
                                index = index + 1;
                                train_nonstop{end+1} = app.train_valuable.TrainList(i);

                            end
                        end
                    end
                end
            end
            for i = 1:length(train_nonstop)
                app.train_valuable.deleteTrain(train_nonstop{i}); %删掉可以直达的车辆
                app.train_end_valuable.deleteTrain(train_nonstop{i}); %删掉可以直达的车辆
            end
            
            % 中转火车的搜索
            for i = 1:length(app.train_valuable.TrainList)
                first_train = app.train_valuable.TrainList(i);
                for m = first_train.find_start(start)+1 : size(first_train.stops,1)
                    for j = 1:length(app.train_end_valuable.TrainList)
                        second_train = app.train_end_valuable.TrainList(j);
                        if first_train.train_id ~= second_train.train_id
                            for n = 1 : second_train.find_end(ending)-1
                                if contains(first_train.stops{m,1},second_train.stops{n,1})
                                    intermediate_arrive_time = datetime(first_train.stops{m,2},'InputFormat','HH:mm');  
                                    intermediate_departure_time = datetime(second_train.stops{n,3},'InputFormat','HH:mm');
                                    if intermediate_arrive_time < intermediate_departure_time %保证中转站出发时间在到达时间之后（有时间段控制？）
                                        depature_time = first_train.Find_depature_Time(start); 
                                        data_depature_time = datetime(depature_time,'InputFormat','HH:mm');
                                        if data_depature_time - app.time >= five_minutes %保证出发前5min查到票
                                            arrive_time = second_train.Find_arrive_Time(ending);
                                            ID = strcat(first_train.train_id,'中转',second_train.train_id);
                                            start_index = first_train.find_start(start);
                                            end_index = second_train.find_end(ending);
                                            num_available_table1 = first_train.Find_num_table(start_index,m);%余票1
                                            num_available_table2 = second_train.Find_num_table(n,end_index);%余票1
                                            if num_available_table2 < num_available_table1
                                                num_available_table = num_available_table2;
                                            else
                                                num_available_table = num_available_table1;
                                            end %选择我两辆车中余票少的
                                            price = (m - start_index) * first_train.price + (end_index - n) * second_train.price;
                                            intermediate_single_plan = {ID %火车编号
                                                first_train.stops{m,1} %中转站
                                                depature_time %出发时间
                                                arrive_time %到达时间
                                                num2str(num_available_table) %余票
                                                num2str(price)%价格
                                                1 %表示直达
                                                first_train %第一火车
                                                second_train %第二火车
                                                start_index %出发站
                                                end_index %到达站
                                                }; 
                                            app.available_plan{end+1} = intermediate_single_plan;
                                            index = index +1;
                                        end

                                    end
                                end
                            end
                        end
                    end
                end
            end

            if index == 0
                msgbox('未找到匹配的车次', '提示');
                set(app.UIFigure,'Visible', 'off'); %暂时隐藏查询界面
                queryUI = Query; %重新打开一个界面，初始化参数
                queryUI.UIFigure.Name = strcat(app.backend.user_id,' Query Ticket UI');
                queryUI.traindb = app.traindb;
                queryUI.backend = app.backend;
                app.backend.QueryUI = queryUI;
                close(app.UIFigure);
                
            else
                app.train_show = Train_change; %创建一个改签列车展示界面
                app.train_show.UIFigure.Name = strcat(app.backend.user_id,' Ticket List UI');
                set(app.train_show.UIFigure, 'Visible', 'off'); %暂时隐藏列车展示界面
                app.train_show.traindb = app.traindb; %火车数据库
                app.train_show.backend = app.backend;
                app.backend.Train_change_UI = app.train_show;
                app.train_show.show_train(app.available_plan,single_ticket);
                set(app.UIFigure, 'Visible', 'off');
                set(app.train_show.UIFigure, 'Visible', 'on');
                close(app.UIFigure);
            end          
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.train_valuable = TrainDB;
            app.train_end_valuable = TrainDB;
            %app.UIFigure.Name = strcat(app.backend.user_id,'的查询界面');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            start = app.DropDown_3.Value;
            ending = app.DropDown_4.Value;
            if(strcmp(start,ending))
                msgbox(strcat('很担歉,按您的查询条件,当前未找到从',start,'到',start,'的列车'), '提示');
            else
                find_train(app,start,ending);
            end
        end

        % Value changed function: Button_5
        function Button_5ValueChanged(app, event)
            UserTicketUI = UserTicket;
            UserTicketUI.UIFigure.Name = strcat(app.backend.user_id,' My Tickets UI');
            app.backend.UserTicketUI = UserTicketUI;
            app.backend.UserTicketUIstate = true;
            UserTicketUI.backend = app.backend;
            UserTicketUI.show_plan();
            close(app.UIFigure);
            
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            
        end

        % Drop down opening function: DropDown_3
        function DropDown_3Opening(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '查询车票';
            app.Image2 = uiimage(app.Tab_2);
            app.Image2.ImageSource = 'back.jpg';
            app.DropDown_3 = uidropdown(app.Tab_2);
            app.DropDown_3.Items = {'上海', '苏州', '常州', '嘉兴', '溧阳', '湖州', '南京', '杭州'};
            app.DropDown_3.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_3Opening, true);
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = '上海';
            app.DropDown_4 = uidropdown(app.Tab_2);
            app.DropDown_4.Items = {'杭州', '苏州', '常州', '嘉兴', '湖州', '溧阳', '南京', '上海'};
            app.DropDown_4.Value = '杭州';
            app.Button_4 = uibutton(app.Tab_2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '查询车票';
            app.Image = uiimage(app.Tab_2);
            app.Image.ImageSource = 'query1.png';
            app.Image3 = uiimage(app.Tab_2);
            app.Image3.ImageSource = '12306.png';
            app.Button_5 = uibutton(app.Tab_2, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button_5ValueChanged, true);
            app.Button_5.Text = '我的订单';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Query

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end