classdef Register < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TextArea_4       matlab.ui.control.TextArea
        Button_2         matlab.ui.control.Button
        Button           matlab.ui.control.Button
        Label_2          matlab.ui.control.Label
        TextArea_3       matlab.ui.control.TextArea
        TextArea_3Label  matlab.ui.control.Label
        TextArea_2       matlab.ui.control.TextArea
        TextArea_2Label  matlab.ui.control.Label
        TextArea         matlab.ui.control.TextArea
        Label            matlab.ui.control.Label
    end

    
    properties (Access = public)
        user % Description
        login_ui
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            id = app.TextArea.Value;
            password1_ = app.TextArea_2.Value;
            password2_ = app.TextArea_3.Value;
            if strcmp(password1_,password2_)
                app.user.register(id,password1_);
                msgbox('注册成功', '提示');
                set(app.login_ui.CounterUIFigure,'Visible', 'on');
                delete(app.UIFigure);
                return
            else
               errordlg('两次密码不一致，请重新输入', '错误');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '账号';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea_2Label = uilabel(app.UIFigure);
            app.TextArea_2Label.Text = '密码';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Tag = 'password1';
            app.TextArea_3Label = uilabel(app.UIFigure);
            app.TextArea_3Label.Text = '确认密码';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Tag = 'password2';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Interpreter = 'html';
            app.Label_2.Text = {'注册账户'; ''};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确认';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '取消';
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Value = {'10:00'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Register

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end