classdef Train_change < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button_4  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        Image     matlab.ui.control.Image
        UITable   matlab.ui.control.Table
    end

    
    properties (Access = public)
        traindb % Description
        backend
        TextBox        
        OrderUI
        now_plan
        buttons
        ticket
    end
    
    methods (Access = public)
        
        function show_train(app,available_plan,single_ticket)
            app.ticket = single_ticket;
            app.now_plan = available_plan;
            Y_position = 546;
            numButtons = size(available_plan,2);
            app.buttons = gobjects(1, numButtons);
            callbacks = cell(1, numButtons);
            for i = 1:size(available_plan,2)
                if available_plan{i}{7} == 0 %直达列车
                    start_index = available_plan{i}{9};
                    end_index = available_plan{i}{10};
                    train = available_plan{i}{8};
                    start_station = train.stops{start_index,1};
                    end_station = train.stops{end_index,1};
                else
                    start_index = available_plan{i}{10}; %第一辆车的起始站
                    end_index = available_plan{i}{11}; %第二辆车的终点站
                    start_station = available_plan{i}{8}.stops{start_index,1};
                    end_station = available_plan{i}{9}.stops{end_index,1};                  
                end                
                app.TextBox = uitextarea(app.UIFigure);
                app.TextBox.Position = [2,Y_position,100,22];
                app.TextBox.FontSize = 10; % 设置字体大小为12
                app.TextBox.HorizontalAlignment = 'center'; % 设置字体居中
                app.TextBox.Value = available_plan{i}{1};
                app.TextBox = uitextarea(app.UIFigure);
                app.TextBox.Position = [102,Y_position,100,22];
                app.TextBox.FontSize = 12; % 设置字体大小为12
                app.TextBox.HorizontalAlignment = 'center'; % 设置字体居中
                app.TextBox.Value = available_plan{i}{2}; 
                app.TextBox = uitextarea(app.UIFigure);
                app.TextBox.Position = [202,Y_position,100,22];
                app.TextBox.FontSize = 12; % 设置字体大小为12
                app.TextBox.HorizontalAlignment = 'center'; % 设置字体居中
                app.TextBox.Value = start_station;
                app.TextBox = uitextarea(app.UIFigure);
                app.TextBox.Position = [302,Y_position,100,22];
                app.TextBox.FontSize = 12; % 设置字体大小为12
                app.TextBox.HorizontalAlignment = 'center'; % 设置字体居中
                app.TextBox.Value = end_station;
                app.TextBox = uitextarea(app.UIFigure);
                app.TextBox.Position = [402,Y_position,100,22];
                app.TextBox.FontSize = 12; % 设置字体大小为12
                app.TextBox.HorizontalAlignment = 'center'; % 设置字体居中
                app.TextBox.Value = available_plan{i}{3};
                app.TextBox = uitextarea(app.UIFigure);
                app.TextBox.Position = [502,Y_position,100,22];
                app.TextBox.FontSize = 12; % 设置字体大小为12
                app.TextBox.HorizontalAlignment = 'center'; % 设置字体居中
                app.TextBox.Value = available_plan{i}{4};
                start_time = datetime(available_plan{i}{3},'InputFormat','HH:mm');
                end_time = datetime(available_plan{i}{4},'InputFormat','HH:mm');
                cost_time = end_time - start_time;
                app.TextBox = uitextarea(app.UIFigure);
                app.TextBox.Position = [602,Y_position,100,22];
                app.TextBox.FontSize = 12; % 设置字体大小为12
                app.TextBox.HorizontalAlignment = 'center'; % 设置字体居中
                app.TextBox.Value = datestr(cost_time,"HH:MM");
                app.TextBox = uitextarea(app.UIFigure);
                app.TextBox.Position = [702,Y_position,100,22];
                app.TextBox.FontSize = 12; % 设置字体大小为12
                app.TextBox.HorizontalAlignment = 'center'; % 设置字体居中
                app.TextBox.Value = available_plan{i}{5};
                app.TextBox = uitextarea(app.UIFigure);
                app.TextBox.Position = [802,Y_position,100,22];
                app.TextBox.FontSize = 12; % 设置字体大小为12
                app.TextBox.HorizontalAlignment = 'center'; % 设置字体居中
                app.TextBox.Value = available_plan{i}{5};
                % 创建按钮
                app.buttons(i) = uibutton(app.UIFigure, 'Text', '确认', 'Position', [902,Y_position,80,22]);
                % 定义回调函数
                callbacks{i} = {@app.buttonCallback, available_plan{i},single_ticket};
                % 设置按钮的回调函数
                app.buttons(i).ButtonPushedFcn = callbacks{i};
                Y_position = Y_position - 23;
            end
        end

        function buttonCallback(app,src, event,single_plan,single_ticket)
                % 在这里编写按钮点击后的操作，可以使用按钮的索引 buttonIndex

                app.backend.table_manage_back(single_ticket);
                if(app.Find_num_available_ticket(single_plan) == 0)
                    app.backend.table_manage(single_ticket);
                    msgbox('该车次余票为0，请选择其他车次', '提示');
                elseif(datetime(single_plan{3},'InputFormat','HH:mm') - app.backend.Clock <= duration(00,05,00))
                    app.backend.table_manage(single_ticket);
                    msgbox('该车次购票时间已过，请选择其他车次','提示');                
                elseif (app.backend.ticket_list.time_conflict_handle(single_plan{3},single_plan{4}))
                    app.backend.table_manage(single_ticket);
                    msgbox('该车次与已购买车票时间有冲突，请选择其他车次','提示');
                else
                    app.backend.table_manage(single_plan);
                    queryUI = Query;
                    queryUI.UIFigure.Name = strcat(app.backend.user_id,' Query Ticket UI');
                    queryUI.traindb = app.backend.train_db;
                    queryUI.backend = app.backend;
                    app.backend.QueryUI = queryUI;
                    close(app.UIFigure);
                end

            end

        function num_available_table = Find_num_available_ticket(app,single_plan)
            % 更新余票
            if single_plan{7} == 0 %直达列车
                start_index = single_plan{9};
                end_index = single_plan{10};
                train = single_plan{8};
                num_available_table = train.Find_num_table(start_index,end_index);%余票
                return
            else
                intermediate_station = single_plan{2};
                start_index = single_plan{10}; %第一辆车的起始站
                intermediate_end_index = single_plan{8}.find_end(intermediate_station); %第一辆车的终点站
                intermediate_start_index = single_plan{9}.find_start(intermediate_station); %第二辆车的起始站
                end_index = single_plan{11}; %第二辆车的终点站
                num_available_table1 = single_plan{8}.Find_num_table(start_index,intermediate_end_index);%余票1
                num_available_table2 = single_plan{9}.Find_num_table(intermediate_start_index,end_index);%余票1
                if num_available_table2 < num_available_table1
                    num_available_table = num_available_table2;
                    return
                else
                    num_available_table = num_available_table1;
                    return
                end %选择我两辆车中余票少的
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed2(app, event)

            queryUI = Query;
            queryUI.UIFigure.Name = strcat(app.backend.user_id,' Query Ticket UI');
            queryUI.traindb = app.traindb;
            queryUI.backend = app.backend;
            app.backend.QueryUI = queryUI;
            close(app.UIFigure);
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %最小价格排序
            sortingColumn = cellfun(@(x) str2double(x{6}), app.now_plan);
            [~, sortedIndices] = sort(sortingColumn);
            sortedCellArray = app.now_plan(sortedIndices);
            new_train_show = Train_change; %创建一个列车展示界面
            new_train_show.UIFigure.Name = strcat(app.backend.user_id,' Ticket List UI');
            set(new_train_show.UIFigure, 'Visible', 'off'); %暂时隐藏列车展示界面
            new_train_show.traindb = app.traindb; %火车数据库
            new_train_show.backend = app.backend;
            app.backend.Train_change_UI = new_train_show;
            new_train_show.show_train(sortedCellArray,app.ticket);
            set(app.UIFigure, 'Visible', 'off');
            set(new_train_show.UIFigure, 'Visible', 'on');
            close(app.UIFigure);             
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %最早到时排序
            sortingColumn = cellfun(@(x) datetime(x{4},'InputFormat','HH:mm'), app.now_plan);
            [~, sortedIndices] = sort(sortingColumn);
            sortedCellArray = app.now_plan(sortedIndices);
            new_train_show = Train_change; %创建一个列车展示界面
            new_train_show.UIFigure.Name = strcat(app.backend.user_id,' Ticket List UI');
            set(new_train_show.UIFigure, 'Visible', 'off'); %暂时隐藏列车展示界面
            new_train_show.traindb = app.traindb; %火车数据库
            new_train_show.backend = app.backend;
            app.backend.Train_change_UI = new_train_show;
            new_train_show.show_train(sortedCellArray,app.ticket);
            set(app.UIFigure, 'Visible', 'off');
            set(new_train_show.UIFigure, 'Visible', 'on');
            close(app.UIFigure);           
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %最少耗时排序
            sortingColumn = cellfun(@(x) datetime(x{4},'InputFormat','HH:mm') - datetime(x{3},'InputFormat','HH:mm'), app.now_plan);
            [~, sortedIndices] = sort(sortingColumn);
            sortedCellArray = app.now_plan(sortedIndices);
            new_train_show = Train_change; %创建一个列车展示界面
            new_train_show.UIFigure.Name = strcat(app.backend.user_id,' Ticket List UI');
            set(new_train_show.UIFigure, 'Visible', 'off'); %暂时隐藏列车展示界面
            new_train_show.traindb = app.traindb; %火车数据库
            new_train_show.backend = app.backend;
            app.backend.Train_change_UI = new_train_show;
            new_train_show.show_train(sortedCellArray,app.ticket);
            set(app.UIFigure, 'Visible', 'off');
            set(new_train_show.UIFigure, 'Visible', 'on');
            close(app.UIFigure);             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'车次编号'; '中转站'; '起始站'; '终点站'; '起始时间'; '到达时间'; '总共耗时'; '余票'; '价格'};
            app.UITable.RowName = {};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'train_background.jpeg';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Text = '返回';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '最小价格';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '最早到时';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '最少耗时';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Train_change

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end