classdef UserTicket < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button_3  matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        Image     matlab.ui.control.Image
        UITable   matlab.ui.control.Table
    end

    
    properties (Access = public)
        TextBox
        Label
        backend
        single_ticket
        single_ticket_state
        buttons
    end
    
    methods (Access = public)
        
        function show_plan(app)
            Y_position = 428;
            ticket_list = app.backend.ticket_list.ticket_list;
            numButtons = size(ticket_list,2);
            app.buttons = gobjects(1, numButtons);
            callbacks = cell(1, numButtons);
            for i = 1:size(ticket_list,2)
                app.TextBox = uitextarea(app.UIFigure);
                app.TextBox.Position = [1,Y_position,110,22];
                app.TextBox.FontSize = 12; % 设置字体大小为12
                app.TextBox.HorizontalAlignment = 'center'; % 设置字体居中
                app.TextBox.Value = app.backend.user_id;
                app.TextBox = uitextarea(app.UIFigure);
                app.TextBox.Position = [101,Y_position,110,22];
                app.TextBox.FontSize = 12; % 设置字体大小为12
                app.TextBox.HorizontalAlignment = 'center'; % 设置字体居中
                app.TextBox.Value = ticket_list{i}{1}; 
                app.TextBox = uitextarea(app.UIFigure);
                app.TextBox.Position = [201,Y_position,110,22];
                app.TextBox.FontSize = 12; % 设置字体大小为12
                app.TextBox.HorizontalAlignment = 'center'; % 设置字体居中
                app.TextBox.Value = ticket_list{i}{8}.stops{ticket_list{i}{9},1};
                app.TextBox = uitextarea(app.UIFigure);
                app.TextBox.Position = [301,Y_position,110,22];
                app.TextBox.FontSize = 12; % 设置字体大小为12
                app.TextBox.HorizontalAlignment = 'center'; % 设置字体居中
                app.TextBox.Value = ticket_list{i}{8}.stops{ticket_list{i}{10},1};
                app.TextBox = uitextarea(app.UIFigure);
                app.TextBox.Position = [401,Y_position,100,22];
                app.TextBox.FontSize = 12; % 设置字体大小为12
                app.TextBox.HorizontalAlignment = 'center'; % 设置字体居中
                app.TextBox.Value = ticket_list{i}{3};
                app.TextBox = uitextarea(app.UIFigure);
                app.TextBox.Position = [501,Y_position,100,22];
                app.TextBox.FontSize = 12; % 设置字体大小为12
                app.TextBox.HorizontalAlignment = 'center'; % 设置字体居中
                app.TextBox.Value = ticket_list{i}{4};
                app.TextBox = uitextarea(app.UIFigure);
                app.TextBox.Position = [601,Y_position,100,22];
                app.TextBox.FontSize = 12; % 设置字体大小为12
                app.TextBox.HorizontalAlignment = 'center'; % 设置字体居中
                app.TextBox.Value = ticket_list{i}{6};                
                % 创建按钮
                app.buttons(i) = uibutton(app.UIFigure, 'Text', '选择', 'Position', [701,Y_position,100,22]);
                % 定义回调函数
                callbacks{i} = {@app.buttonCallback, ticket_list{i}};%待修改
                % 设置按钮的回调函数
                app.buttons(i).ButtonPushedFcn = callbacks{i};
                Y_position = Y_position - 23;
            end
        end
        function buttonCallback(app,src, event, single_plan)
                % 在这里编写按钮点击后的操作，可以使用按钮的索引 buttonIndex
                app.single_ticket = single_plan;
                app.single_ticket_state = true;
            end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.UIFigure.Name = strcat(app.backend.user_id,'的历史车票界面');
            app.single_ticket_state = false;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %退票
            if app.single_ticket_state
                app.backend.table_manage_back(app.single_ticket);
                queryUI = Query;
                queryUI.UIFigure.Name = strcat(app.backend.user_id,' Query Ticket UI');
                app.backend.UserTicketUIstate = false;
                queryUI.traindb = app.backend.train_db;
                queryUI.backend = app.backend;
                app.backend.QueryUI = queryUI;
                close(app.UIFigure);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %改签            
            if app.single_ticket_state 
                if app.single_ticket{7} == 0 %直达列车
                    start_index = app.single_ticket{9};
                    end_index = app.single_ticket{10};                
                    start_station = app.single_ticket{8}.stops{start_index,1};
                    end_station = app.single_ticket{8}.stops{end_index,1};
                else
                    start_index = app.single_ticket{10}; %第一辆车的起始站
                    end_index = app.single_ticket{11}; %第二辆车的终点站
                    start_station = app.single_ticket{8}.stops{start_index,1};
                    end_station = app.single_ticket{9}.stops{end_index,1};
                end
                queryUI = Query;
                queryUI.UIFigure.Name = strcat(app.backend.user_id,' Query Ticket UI');
                set(queryUI.UIFigure, 'Visible', 'off')
                app.backend.UserTicketUIstate = false;
                queryUI.traindb = app.backend.train_db;
                queryUI.backend = app.backend;
                queryUI.find_train_except(start_station,end_station,app.single_ticket);
                close(app.UIFigure);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            queryUI = Query;
            queryUI.UIFigure.Name = strcat(app.backend.user_id,' Query Ticket UI');
            app.backend.UserTicketUIstate = false;
            queryUI.traindb = app.backend.train_db;
            queryUI.backend = app.backend;
            app.backend.QueryUI = queryUI;
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'用户名'; '车次编号'; '起始站'; '终点站'; '起始时间'; '到达时间'; '价格'};
            app.UITable.RowName = {};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'user_ticket_background.jpg';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '退票';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '改签';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '返回';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserTicket

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end