classdef front < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SystemTimeSimulationControlUIUIFigure  matlab.ui.Figure
        EditField_3   matlab.ui.control.NumericEditField
        Label_4       matlab.ui.control.Label
        EditField_2   matlab.ui.control.NumericEditField
        Label_3       matlab.ui.control.Label
        EditField     matlab.ui.control.NumericEditField
        Label_2       matlab.ui.control.Label
        DropDown      matlab.ui.control.DropDown
        Label         matlab.ui.control.Label
        ClockLabel_2  matlab.ui.control.Label
        ClockLabel    matlab.ui.control.Label
        Button        matlab.ui.control.StateButton
        Image2        matlab.ui.control.Image
    end

    
    properties (Access = private)
        controller % Description
        hour
        minute
        second
    end


    methods (Access = public)
        
        function setAppController(app, controller)
            app.controller = controller;
            app.hour = '0';
            app.minute = '0';
            app.second = '0';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: SystemTimeSimulationControlUIUIFigure
        function SystemTimeSimulationControlUIUIFigureCloseRequest(app, event)

            delete(app);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.second = num2str(app.EditField_3.Value);
            app.controller.setFunction(app.hour,app.minute,app.second);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.minute = num2str(app.EditField_2.Value);
            app.controller.setFunction(app.hour,app.minute,app.second);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.hour = num2str(app.EditField.Value);
            app.controller.setFunction(app.hour,app.minute,app.second);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.controller.setspeed;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SystemTimeSimulationControlUIUIFigure = uifigure('Visible', 'off');
            app.SystemTimeSimulationControlUIUIFigure.Name = 'System Time Simulation Control UI';
            app.SystemTimeSimulationControlUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @SystemTimeSimulationControlUIUIFigureCloseRequest, true);
            app.Image2 = uiimage(app.SystemTimeSimulationControlUIUIFigure);
            app.Image2.ImageSource = 'time_background.jpg';
            app.Button = uibutton(app.SystemTimeSimulationControlUIUIFigure, 'state');
            app.Button.Text = '开始/暂停模拟';
            app.ClockLabel = uilabel(app.SystemTimeSimulationControlUIUIFigure);
            app.ClockLabel.Text = '00:00:00';
            app.ClockLabel_2 = uilabel(app.SystemTimeSimulationControlUIUIFigure);
            app.ClockLabel_2.Text = '系统时间控制';
            app.Label = uilabel(app.SystemTimeSimulationControlUIUIFigure);
            app.Label.Text = '时间流逝速度';
            app.DropDown = uidropdown(app.SystemTimeSimulationControlUIUIFigure);
            app.DropDown.Items = {'5秒/1秒', '10秒/1秒', '20秒/1秒'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '5秒/1秒';
            app.Label_2 = uilabel(app.SystemTimeSimulationControlUIUIFigure);
            app.Label_2.Text = '时';
            app.EditField = uieditfield(app.SystemTimeSimulationControlUIUIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Label_3 = uilabel(app.SystemTimeSimulationControlUIUIFigure);
            app.Label_3.Text = '分';
            app.EditField_2 = uieditfield(app.SystemTimeSimulationControlUIUIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.Label_4 = uilabel(app.SystemTimeSimulationControlUIUIFigure);
            app.Label_4.Text = '秒';
            app.EditField_3 = uieditfield(app.SystemTimeSimulationControlUIUIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            % Show the figure after all components are created
            app.SystemTimeSimulationControlUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = front

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SystemTimeSimulationControlUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SystemTimeSimulationControlUIUIFigure)
        end
    end
end